classdef mainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainWin                        matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadDataMenu                   matlab.ui.container.Menu
        LoadfromRawMenu                matlab.ui.container.Menu
        LoadfromGUIstateMenu           matlab.ui.container.Menu
        batchloadMenu                  matlab.ui.container.Menu
        reloadsortingresultsMenu       matlab.ui.container.Menu
        SaveDataMenu                   matlab.ui.container.Menu
        ExportFullMenu                 matlab.ui.container.Menu
        ExporCurSelMenu                matlab.ui.container.Menu
        ObjectsCurrentSelectionMenu    matlab.ui.container.Menu
        DeleteDataMenu                 matlab.ui.container.Menu
        DeleteDatasetsMenu             matlab.ui.container.Menu
        DeleteTrialsMenu               matlab.ui.container.Menu
        DeleteCellsMenu                matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        SetDefaultsMenu                matlab.ui.container.Menu
        GUIMenu                        matlab.ui.container.Menu
        GeneralPlottingMenu            matlab.ui.container.Menu
        MapsMenu                       matlab.ui.container.Menu
        RateMenu                       matlab.ui.container.Menu
        DirMenu                        matlab.ui.container.Menu
        LinearMenu                     matlab.ui.container.Menu
        ObjectVectMenu                 matlab.ui.container.Menu
        SpeedMenu                      matlab.ui.container.Menu
        sACMenu                        matlab.ui.container.Menu
        GridcellpropscalcMenu          matlab.ui.container.Menu
        RestoreGUIdefaultsMenu         matlab.ui.container.Menu
        GUIparamstoobjectsMenu         matlab.ui.container.Menu
        PauseUpdatesMenu               matlab.ui.container.Menu
        FreezeMenu                     matlab.ui.container.Menu
        UnFreezeMenu                   matlab.ui.container.Menu
        DataMenu                       matlab.ui.container.Menu
        AddMetaDataMenu                matlab.ui.container.Menu
        addsinglefieldMenu             matlab.ui.container.Menu
        getnExpMenu                    matlab.ui.container.Menu
        FilterMenu                     matlab.ui.container.Menu
        ByClustLabelMenu               matlab.ui.container.Menu
        ByNSpikesMenu                  matlab.ui.container.Menu
        BySpatialPropsMenu             matlab.ui.container.Menu
        spatialInfoMenu                matlab.ui.container.Menu
        gridnessMenu                   matlab.ui.container.Menu
        rayleighvectorMenu             matlab.ui.container.Menu
        BySelectionMenu                matlab.ui.container.Menu
        ByShuffleMenu                  matlab.ui.container.Menu
        CustomMenu                     matlab.ui.container.Menu
        RemoveFilterMenu               matlab.ui.container.Menu
        ReorderMenu                    matlab.ui.container.Menu
        DatasetsMenu                   matlab.ui.container.Menu
        FiguresMenu                    matlab.ui.container.Menu
        DACQMenu                       matlab.ui.container.Menu
        DatasetMenu                    matlab.ui.container.Menu
        PlotAllMapsMenu                matlab.ui.container.Menu
        PlotAllUGotbettergogetacoffeeMenu  matlab.ui.container.Menu
        NeuropixelsMenu                matlab.ui.container.Menu
        plotWaveformMenu               matlab.ui.container.Menu
        plotWaveDistonProbeMenu        matlab.ui.container.Menu
        MapsMenu_Figures               matlab.ui.container.Menu
        FilterbytimeMenu               matlab.ui.container.Menu
        FilterbyDirMenu                matlab.ui.container.Menu
        PlotGridPropsMenu              matlab.ui.container.Menu
        SaveasPDFMenu                  matlab.ui.container.Menu
        AllMenu                        matlab.ui.container.Menu
        CustomSelectionMenu            matlab.ui.container.Menu
        AnalysisMenu                   matlab.ui.container.Menu
        SpatialpropsMenu               matlab.ui.container.Menu
        gridnessMenu_analysis          matlab.ui.container.Menu
        spatialinfoMenu_analysis       matlab.ui.container.Menu
        rayleighvectorlengthMenu_analysis  matlab.ui.container.Menu
        borderscoreMenu                matlab.ui.container.Menu
        spatialcorrelationMenu         matlab.ui.container.Menu
        TextArea_4                     matlab.ui.control.TextArea
        CheckBoxToggleSel              matlab.ui.control.CheckBox
        AgeEditField                   matlab.ui.control.EditField
        AgeEditFieldLabel              matlab.ui.control.Label
        SelectAllDatasets              matlab.ui.control.Button
        BatchMapsButton                matlab.ui.control.Button
        ExportFiguresButton            matlab.ui.control.Button
        SelectAllTrials                matlab.ui.control.Button
        SelectAllCells                 matlab.ui.control.Button
        SelectDatasetsListBox          matlab.ui.control.ListBox
        SelectDatasetsListBoxLabel     matlab.ui.control.Label
        CellEditField                  matlab.ui.control.EditField
        CellEditFieldLabel             matlab.ui.control.Label
        TrialEditField                 matlab.ui.control.EditField
        TrialEditFieldLabel            matlab.ui.control.Label
        CurrentDatasetEditField        matlab.ui.control.EditField
        DatasetLabel                   matlab.ui.control.Label
        LoadDataButton                 matlab.ui.control.Button
        PlotSelectionButton            matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        OverviewTab                    matlab.ui.container.Tab
        cluLabel                       matlab.ui.control.EditField
        cluLabelTxt                    matlab.ui.control.EditField
        varMapList                     matlab.ui.control.ListBox
        SelectPlotstyleLabel           matlab.ui.control.Label
        PanelAllTrials                 matlab.ui.container.Panel
        MakeDMapsButton                matlab.ui.control.Button
        LoadWaveForms                  matlab.ui.control.Button
        varmapButton                   matlab.ui.control.Button
        MakeRMapsButton                matlab.ui.control.Button
        isod                           matlab.ui.control.NumericEditField
        lratio                         matlab.ui.control.NumericEditField
        amplitude                      matlab.ui.control.NumericEditField
        lratioTxt                      matlab.ui.control.EditField
        isodTxt                        matlab.ui.control.EditField
        amplitudeTxt                   matlab.ui.control.EditField
        nSpikes                        matlab.ui.control.NumericEditField
        nSpikesTxt                     matlab.ui.control.EditField
        ac_longLag                     matlab.ui.control.UIAxes
        dmap                           matlab.ui.control.UIAxes
        varmap                         matlab.ui.control.UIAxes
        rmap                           matlab.ui.control.UIAxes
        spikepos                       matlab.ui.control.UIAxes
        ac_shortLag                    matlab.ui.control.UIAxes
        wfs                            matlab.ui.control.UIAxes
        CompareCellsTab                matlab.ui.container.Tab
        PlotmapsCheckBox               matlab.ui.control.CheckBox
        CC_maxChanCell3                matlab.ui.control.EditField
        CC_maxChanCell2                matlab.ui.control.EditField
        CC_maxChanCell1                matlab.ui.control.EditField
        CC_cell_ID1_1                  matlab.ui.control.EditField
        PlotClusterSpaceButton         matlab.ui.control.Button
        PlotWFSelectionButton          matlab.ui.control.Button
        PlotCGSelectionButton          matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        CC_cell_ID3_3                  matlab.ui.control.EditField
        CC_cell_ID2_2                  matlab.ui.control.EditField
        CC_cell_ID3                    matlab.ui.control.EditField
        CC_lagEditField                matlab.ui.control.NumericEditField
        lagEditFieldLabel              matlab.ui.control.Label
        CC_binSzEditField              matlab.ui.control.NumericEditField
        binSzEditFieldLabel            matlab.ui.control.Label
        CC_cell_ID2                    matlab.ui.control.EditField
        CC_cell_ID1                    matlab.ui.control.EditField
        wf2                            matlab.ui.control.UIAxes
        wf1                            matlab.ui.control.UIAxes
        wf3                            matlab.ui.control.UIAxes
        ac3                            matlab.ui.control.UIAxes
        cg1_2                          matlab.ui.control.UIAxes
        ac2                            matlab.ui.control.UIAxes
        cg1_3                          matlab.ui.control.UIAxes
        cg2_3                          matlab.ui.control.UIAxes
        ac1                            matlab.ui.control.UIAxes
        LineariseItTab                 matlab.ui.container.Tab
        TrackTypeEditField             matlab.ui.control.EditField
        TrackTypeEditFieldLabel        matlab.ui.control.Label
        TrackLengthEditField           matlab.ui.control.NumericEditField
        TrackLengthcmEditFieldLabel_2  matlab.ui.control.Label
        RundirectionDropDown           matlab.ui.control.DropDown
        RundirectionDropDownLabel      matlab.ui.control.Label
        Lin_trialID                    matlab.ui.control.EditField
        TextArea_3                     matlab.ui.control.TextArea
        Lin_cellID                     matlab.ui.control.EditField
        LineariseItButton              matlab.ui.control.Button
        TextArea_2                     matlab.ui.control.TextArea
        lin_rate                       matlab.ui.control.UIAxes
        lin_spikebypos                 matlab.ui.control.UIAxes
        lin_allCells                   matlab.ui.control.UIAxes
        LFPbrowserTab                  matlab.ui.container.Tab
        SaveDataButton                 matlab.ui.control.Button
        CellsinDatasetListBox          matlab.ui.control.ListBox
        CellsinDatasetListBoxLabel     matlab.ui.control.Label
        TrialsinDatasetListBox         matlab.ui.control.ListBox
        TrialsinDatasetListBoxLabel    matlab.ui.control.Label
    end

       
    % helpers
    methods (Access = private)
        
        function setFieldsGUI(app,event)
        
            if isempty(app.mainWin.UserData.dataObj)
                return;
            end
            
            currDsSelInd = app.mainWin.UserData.currSelInd(1);
            currTrSelInd = app.mainWin.UserData.currSelInd(2);
            currCeSelInd = app.mainWin.UserData.currSelInd(3);
            
            app.CurrentDatasetEditField.Value = app.mainWin.UserData.dataObj{currDsSelInd}.dataSetName;
            if length(app.SelectDatasetsListBox.Value) == 1; app.SelectDatasetsListBox.Value = app.SelectDatasetsListBox.Items{currDsSelInd}; end
            % trial & cell selection
            app.TrialEditField.Value          = app.mainWin.UserData.dataObj{currDsSelInd}.trialNames{currTrSelInd};
            app.CellEditField.Value           = app.mainWin.UserData.cellIDStr{currDsSelInd}{currCeSelInd};
            
            % special case if dataset selection changed or GUI is started or dataset has been deleted
            % - last condition might account for anything other than when fields are empty?
            if isempty(event) || strcmpi(event.Source.Tag,'dsselbox') || isempty(app.TrialsinDatasetListBox.Items) || isempty(app.CellsinDatasetListBox.Items) || ~all(ismember(app.mainWin.UserData.dataObj{currDsSelInd}.trialNames,app.TrialsinDatasetListBox.Items))
                app.TrialsinDatasetListBox.Items = app.mainWin.UserData.dataObj{currDsSelInd}.trialNames;
                app.TrialsinDatasetListBox.Value = app.mainWin.UserData.dataObj{currDsSelInd}.trialNames{1};
                currFilter                       = app.mainWin.UserData.Filter{currDsSelInd};
                app.CellsinDatasetListBox.Items  = app.mainWin.UserData.cellIDStr{currDsSelInd}(currFilter); % this will make sure we keep memory of any filter set in case we load multiple datasets - is that useful?
                if app.mainWin.UserData.toggleCellSel && ~isempty(app.mainWin.UserData.toggleMem{currDsSelInd})
                    app.CellsinDatasetListBox.Value  = app.CellsinDatasetListBox.Items(app.mainWin.UserData.toggleMem{currDsSelInd});
                else
                    app.CellsinDatasetListBox.Value  = app.CellsinDatasetListBox.Items{1};
                end
            else
%                 app.TrialsinDatasetListBox.Value = app.mainWin.UserData.dataObj{currDsSelInd}.trialNames{currTrSelInd};
%                 app.CellsinDatasetListBox.Value  = app.CellsinDatasetListBox.Items{currCeSelInd};
            end

            % only activate 'RemoveFilter' option if you filtered some data
            if sum(app.mainWin.UserData.Filter{currDsSelInd}) < length(app.mainWin.UserData.cellIDStr{currDsSelInd})
                app.RemoveFilterMenu.Enable = 'On';
            else
                app.RemoveFilterMenu.Enable = 'Off';
            end
            
            if isfield(app.mainWin.UserData.dataObj{currDsSelInd}.trialMetaData,'age')
                app.AgeEditField.Value = ['P' num2str(app.mainWin.UserData.dataObj{currDsSelInd}.trialMetaData(currTrSelInd).age)];
            else
                app.AgeEditField.Value = 'n/a';
            end

            % different tabs
            switch lower(app.TabGroup.SelectedTab.Title)
                case 'overview'
                    app.nSpikes.Value   = length(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_Times{currTrSelInd}{currCeSelInd});
                    if ~isempty(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{1})
                        tempWFs         = squeeze(mean(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{currTrSelInd}{currCeSelInd,1},1,'omitnan'));
                        if ~all(isnan(tempWFs))
                            app.amplitude.Value  = round(max(abs(min(tempWFs,[],1) - max(tempWFs,[],1))),1);
                        else
                             app.amplitude.Value = 0;
                        end
                    end
                    if strcmp(app.mainWin.UserData.dataType,'npix')
                        app.cluLabel.Value = app.mainWin.UserData.dataObj{currDsSelInd}.cell_Label{currCeSelInd};
                    end
                    %             app.lratio.Value    = length(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_times{currTrSelInd}{currCeSelInd});
                    %             app.isoD.Value      = length(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_times{currTrSelInd}{currCeSelInd});

                    % update figures
                    if app.ac_shortLag.UserData.plot 
                        plotCG(app,currCeSelInd,app.mainWin.UserData.figDefaults.ac_bSz_shortLag,app.mainWin.UserData.figDefaults.ac_lag_shortLag,app.ac_shortLag); 
                    else
                        cla(app.ac_shortLag); 
                        app.ac_shortLag.Visible='On'; 
                    end

                    if app.ac_longLag.UserData.plot 
                        plotCG(app,currCeSelInd,app.mainWin.UserData.figDefaults.ac_bSz_longLag,app.mainWin.UserData.figDefaults.ac_lag_longLag,app.ac_longLag); 
                    else
                        cla(app.ac_longLag); 
                        app.ac_longLag.Visible='On'; 
                    end

                    if app.wfs.UserData.plot;         plotWave(app,currCeSelInd,app.wfs); else; cla(app.wfs);         app.wfs.Visible='On';       end

                    if app.rmap.UserData.plot;        plotRMap(app);                      else; cla(app.rmap);        app.rmap.Visible='On';      end

                    if app.dmap.UserData.plot;        plotDMap(app);                      else; cla(app.dmap);        app.dmap.Visible='On';      end
                   
                    if app.spikepos.UserData.plot;    plotSpkPos(app,event);              else; cla(app.spikepos);    app.spikepos.Visible='On';  end
                    
                    if ~isempty(event) && ~strcmpi(event.Source.Tag,'trialselbox')
                        plotAllTrials(app);
                    end
                 
                    if app.varmap.UserData.plot
                        switch lower(app.varMapList.Value)
                            case 'spatial ac'
                                plot_sACs(app,event);
                            case 'object vect'
                                plot_OVmaps(app,event);
                            case 'speed mod.'
                                plot_SpeedMaps(app);
                        end
                    else
                        cla(app.varmap); app.varmap.Visible='On';
                        app.varmap.Title.String = 'Var. Data Plot';
                    end
                % Compare Cells Tab    
                case 'compare cells'
                                        
                    % [~, cellSelInd] = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);    
                    [~, cellSelInd] = ismember(app.CellsinDatasetListBox.Value, app.mainWin.UserData.cellIDStr{currDsSelInd}); 

                    if length(cellSelInd) > 3
                        cellSelInd = cellSelInd(1:3);
                        warning('scaNpix::MainGUI::Can only compare up to 3 cells in GUI. If you want to compare more use the ''Plot CG selection'' push button!');
                    end
                    
                    plottedCGs = nchoosek(1:3,2);
                    for i = 1:3
                        if i <= length(cellSelInd)
                            plotCG(app,cellSelInd(i),app.CC_binSzEditField.Value,app.CC_lagEditField.Value,app.(['ac' num2str(i)]));
                            if ~isempty(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{currTrSelInd})
                                plotWave(app,cellSelInd(i),app.(['wf' num2str(i)]));
                                tempMean = squeeze(mean(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{currTrSelInd}{cellSelInd(i)},1,'omitnan'));
                                [~, maxInd] = max(abs(min(tempMean,[],1) - max(tempMean,[],1)));
                                app.(['CC_maxChanCell' num2str(i)]).Value = ['maxChan ' num2str(maxInd)];
                            end
                            app.(['CC_cell_ID' num2str(i)]).Value                = app.mainWin.UserData.cellIDStr{currDsSelInd}{cellSelInd(i)};
                            app.(['CC_cell_ID' num2str(i) '_' num2str(i)]).Value = app.mainWin.UserData.cellIDStr{currDsSelInd}{cellSelInd(i)};
                        else
                            cla(app.(['wf' num2str(i)])); app.(['wf' num2str(i)]).Visible='On'; 
                            cla(app.(['ac' num2str(i)])); app.(['ac' num2str(i)]).Visible='On';   
                            app.(['CC_cell_ID' num2str(i)]).Value = '';
                            app.(['CC_cell_ID' num2str(i) '_' num2str(i)]).Value = '';
                            app.(['CC_maxChanCell' num2str(i)]).Value = '';
                        end
                            
                        for j = i+1:length(cellSelInd)
                            plotCG(app,cellSelInd([i j]),app.CC_binSzEditField.Value,app.CC_lagEditField.Value,app.(['cg' num2str(i) '_' num2str(j)]));
                            plottedCGs(i,:) = nan;
                        end  
                    end
                    % delete plots 
                    if length(cellSelInd) < 3
                        plottedCGs(isnan(plottedCGs(:,1)),:) = [];
                        for i = 1:size(plottedCGs,1)
                            cla(app.(['cg' num2str(plottedCGs(i,1)) '_' num2str(plottedCGs(i,2))])); app.(['cg' num2str(plottedCGs(i,1)) '_' num2str(plottedCGs(i,2))]).Visible='On';
                        end
                    end
                % linerarise it panel
                case 'linearise it'
                    
                    [~,mapTypeInd] = find(strcmp(app.RundirectionDropDown.Items,app.RundirectionDropDown.Value));
                    
                    % can we use auto select right track type from trial
                    % type property? prob needs some clever regexp
%                     trialdx = find(strcmpi(app.mainWin.UserData.dataObj{currDsSelInd}.trialMetaData(currTrSelInd).trialType,app.TrialDropDown.Items));
%                     if any(trialdx) 
%                         app.TrialDropDown.Value = app.TrialDropDown.Items{trialdx(1)};
%                     end
                    if ~isempty(app.mainWin.UserData.dataObj{currDsSelInd}.trialMetaData(currTrSelInd).trackLength) 
                        app.TrackLengthEditField.Value = app.mainWin.UserData.dataObj{currDsSelInd}.trialMetaData(currTrSelInd).trackLength;
                    end

                    if isempty(regexp(app.mainWin.UserData.dataObj{currDsSelInd}.trialMetaData(currTrSelInd).trialType,'\w*track','once'))
                        app.TrackTypeEditField.Value = 'n/a';
                    else
                        app.TrackTypeEditField.Value = app.mainWin.UserData.dataObj{currDsSelInd}.trialMetaData(currTrSelInd).trialType;     
                    end
                    
                    if ~isempty(app.mainWin.UserData.dataObj{currDsSelInd}.posData.linXY{currTrSelInd})
                        
                        app.Lin_trialID.Value = app.TrialsinDatasetListBox.Value{1};
                        app.Lin_cellID.Value  = app.CellsinDatasetListBox.Value{1};
                        % plot all cells plot just once
                        if isempty(get(app.lin_allCells,'Children')) || (~strcmp(event.Source.Type, 'uitabgroup') && any(strcmp(event.Source.Items,'CW')))
                            scanpix.plot.plotLinMaps( app.mainWin.UserData.dataObj{currDsSelInd}.maps.lin{currTrSelInd}{mapTypeInd}, app.lin_allCells, 'type','cellpos' );
                        end
                        % plot current cell data
                        scanpix.plot.plotLinMaps( app.mainWin.UserData.dataObj{currDsSelInd}.maps.lin{currTrSelInd}{mapTypeInd}(currCeSelInd,:), app.lin_rate );
                        % plot spike by pos
                        pos      = [app.mainWin.UserData.dataObj{currDsSelInd}.posData.linXY{currTrSelInd}(:,mapTypeInd),(1:length(app.mainWin.UserData.dataObj{currDsSelInd}.posData.linXY{currTrSelInd}))'];
                        scanpix.plot.plotSpikeByPos(pos,app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_Times{currTrSelInd}{currCeSelInd},app.lin_spikebypos,'srate',app.mainWin.UserData.dataObj{currDsSelInd}.params('posFs'),'msize',4,'axvis',true);
                        set(app.lin_spikebypos,'ytick',[0 max(get(app.lin_spikebypos,'ylim'))],'YTickLabel',[0 app.mainWin.UserData.dataObj{currDsSelInd}.trialMetaData(currTrSelInd).duration]);
                    else
                        % clear 
                        cla(app.lin_allCells);   app.lin_allCells.Visible   = 'On';
                        cla(app.lin_rate);       app.lin_rate.Visible       = 'On';
                        cla(app.lin_spikebypos); app.lin_spikebypos.Visible = 'On';
                        app.TrackLengthEditField.Value = 0;
                        app.Lin_trialID.Value          = '';
                        app.Lin_cellID.Value           = '';
                    end
            end
            
            figure(app.mainWin);
        end
        
        function setDefaults(app,type,field,value,mapType)
            
            if nargin == 2; [field, value] = scanpix.GUI.grabDefaults_mainGUI(type); end
        
            for i = 1:length(field)
                switch lower(type)
                    case 'gui'
                        app.mainWin.UserData.GUIDefaults.(field{i}) = value{i};
                    case 'fig'
                        app.mainWin.UserData.figDefaults.(field{i}) = value{i};
                    case 'maps'
                        if nargin == 2
                            app.mainWin.UserData.figDefaults.mapParams.(field{i}) = value{i};
                        else
                            app.mainWin.UserData.figDefaults.mapParams.(mapType).(field{i}) = value{i};
                        end
                end
            end
        end
        
        function plotRMap(app)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{tr_ind}); cla(app.rmap); app.rmap.Visible='On'; return; end

            % plot
            if isempty(app.rmap.Children)
                scanpix.plot.plotRateMap(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{tr_ind}{ce_ind},app.rmap,'colmap',app.mainWin.UserData.figDefaults.rMapColMap,'nsteps',app.mainWin.UserData.figDefaults.rMapColNSteps)
                axis(app.rmap,'square');
            else
            % faster in case figure already contains an image
                app.rmap.Children(end).CData = app.mainWin.UserData.binnedRMaps{ds_ind}{tr_ind}{ce_ind};
                app.rmap.CLim = [0 app.mainWin.UserData.figDefaults.rMapColNSteps+1]; 
            end
            
            if (isfield( app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind),'objPos') && ~isempty(app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).objPos)) || isfield( app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind),'rewardCoords')
                if isfield( app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind),'rewardCoords')
                    binSizePix = floor( app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).ppm/100 * app.mainWin.UserData.figDefaults.mapParams.rate.binSizeSpat );
                    rewCoords = app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).rewardCoords .* (app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).ppm/app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).ppm_org) ./ binSizePix;
                    XData = rewCoords(1:2:end);
                    YData = rewCoords(2:2:end);
                else
                    XData = app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).objPos(1:2:end);
                    YData = app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).objPos(2:2:end);
                end
                if length(app.rmap.Children) == 1
                    hold(app.rmap,'on');
                    scatter(app.rmap,XData,YData,48,'filled','r');
                    hold(app.rmap,'off');
                else
                    app.rmap.Children(1).XData = XData;
                    app.rmap.Children(1).YData = YData;
                end
            end
            mapSz = size(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{tr_ind}{ce_ind});
            set(app.rmap,'xlim',[0.5 mapSz(2)+0.5],'ylim',[0.5 mapSz(1)+0.5]);
            set(get(app.rmap,'xlabel'),'String',['peakFR=' num2str(max(max(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{tr_ind}{ce_ind})),'%.1f')],'Visible','On','VerticalAlignment','top');
        end
        
        function plotDMap(app)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{tr_ind}); cla(app.dmap); app.dmap.Visible='On';  return; end
            
            map = app.mainWin.UserData.dataObj{ds_ind}.maps.dir{tr_ind}{ce_ind};
            
            if isempty(app.dmap.Children)
                scanpix.plot.plotDirMap(map,app.dmap);
            else
                [meanR,meanDir,thetas,rhos] = scanpix.analysis.rayleighVect(map);
                [x,y] = pol2cart(meanDir,meanR);
                app.dmap.Children(1).XData = [0 x];
                app.dmap.Children(1).YData = [0 y];
                [x,y] = pol2cart(thetas, rhos);
                app.dmap.Children(2).XData = [x; x(1)];
                app.dmap.Children(2).YData = [y; y(1)];
            end
            
            set(get(app.dmap,'xlabel'),'String',['peakFR=' num2str(max(max(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{tr_ind}{ce_ind})),'%.1f')],'Visible','On','VerticalAlignment','top');
        end
        
        function plotSpkPos(app,event)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            tr_ind   = app.mainWin.UserData.currSelInd(2);
            ce_ind   = app.mainWin.UserData.currSelInd(3);
            pos      = app.mainWin.UserData.dataObj{ds_ind}.posData.XY{tr_ind};
            spkTimes = app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times{tr_ind}{ce_ind};
%             minVal   = min(vertcat(app.mainWin.UserData.dataObj{ds_ind}.posData.XY{:}));
%             maxVal   = max(vertcat(app.mainWin.UserData.dataObj{ds_ind}.posData.XY{:}));
            if isKey(app.mainWin.UserData.dataObj{ds_ind}.params,'InterpPos2PosFs') && app.mainWin.UserData.dataObj{ds_ind}.params('InterpPos2PosFs')
                posFs = app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).log.InterpPosFs;
            else
                posFs = app.mainWin.UserData.dataObj{ds_ind}.params('posFs');
            end
            %
            if isempty(app.spikepos.Children) || length(app.spikepos.Children(1).XData) ~= length(spkTimes)
                scanpix.plot.plotSpikeByPos(pos,spkTimes,app.spikepos,'srate',posFs,'msize',10);
                axis(app.spikepos,'square');
                set(get(app.spikepos,'xlabel'),'String',' ','Visible','On');
                set(app.spikepos,'xlim',[min(pos(:,1)) max(pos(:,1))],'ylim',[min(pos(:,2)) max(pos(:,2))]); %,'ydir','reverse');
            else
                % update figure data w/o making it from scratch
                % path only needs updating when changing trial or dataset
                if strcmp(event.Source.Tag,'TrialSelBox') %|| strcmp(event.Source.Tag,'DSSelBox')
                    app.spikepos.Children(2).XData = pos(:,1);
                    app.spikepos.Children(2).YData = pos(:,2);
                    set(app.spikepos,'xlim',[min(pos(:,1)) max(pos(:,1))],'ylim',[min(pos(:,2)) max(pos(:,2))]); %,'ydir','reverse');
                end
                % spikes
                spikeInd = ceil(spkTimes .* posFs); % bin spikes
                app.spikepos.Children(1).XData = pos(spikeInd,1);
                app.spikepos.Children(1).YData = pos(spikeInd,2);
            end
        end
        
        function plotWave(app,cellInd,hAx)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            tr_ind   = app.mainWin.UserData.currSelInd(2);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}) || isempty(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}{cellInd,1}); cla(app.wfs); app.wfs.Visible='On'; return; end
            
            scanpix.plot.plotWaveform(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}{cellInd,1}, hAx,'maxWaves',app.mainWin.UserData.figDefaults.nWaveForms,'linew',6);
                            
            if strcmp(app.mainWin.UserData.dataType,'dacq')
                tetN    = app.mainWin.UserData.dataObj{ds_ind}.cell_ID(cellInd,2);
                meanWFs = squeeze(mean(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}{cellInd,1},1,'omitnan'));
                [~, maxChanInd] = max(abs(min(meanWFs,[],1) - max(meanWFs,[],1)));
                if ~isempty(maxChanInd)
                    scaleChan = app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).ADC_fullscale_mv / app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).gains(tetN,maxChanInd) * 1e3;
                    set(app.wfs,'ylim',[-scaleChan scaleChan],'yTick',[-scaleChan scaleChan],'YtickLabel',round([-scaleChan scaleChan]));
                end
            else
                %% NPIX GOES HERE %%%
            end
        end
        
        function plotAllTrials(app)
        
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            ce_ind   = app.mainWin.UserData.currSelInd(3);
            % no maps, no plots
            if ~app.PanelAllTrials.UserData.plot || (isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1}) && isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1}) )
                delete(app.PanelAllTrials.Children);
                return;
            end
            % in case of multiple datasets we need to account for different n of trials
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1}) || isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1})
                if length(app.PanelAllTrials.Children) > length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                    delete(app.PanelAllTrials.Children);
                end
            else
                if length(app.PanelAllTrials.Children)/2 > length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                    delete(app.PanelAllTrials.Children);
                end
            end

            currPosSt = [5,125,120,120]; currPos = currPosSt;
            if ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
                mapSz = max(reshape(cell2mat(cellfun(@(x) size(x{1}), app.mainWin.UserData.dataObj{ds_ind}.maps.rate,'uni',0)),2,[]),[],2);
            end
            nMapStyle = ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1}) + ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1});
            axCheck = length(app.PanelAllTrials.Children) < nMapStyle * length(app.mainWin.UserData.dataObj{ds_ind}.trialNames);
            if app.mainWin.UserData.figDefaults.normColMapByMax
               maxVal = max(cellfun(@(x) max(x(:)),cellfun(@(x) x{ce_ind},app.mainWin.UserData.dataObj{ds_ind}.maps.rate,'uni',0)));
            end
            for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)

                if ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
                    % check if axis exist or not
                    if axCheck && ( isempty(app.PanelAllTrials.Children) || ~any([app.PanelAllTrials.Children(:).UserData] == 2*i-1) )
                        hAx = uiaxes(app.PanelAllTrials,'position',currPos);
                        hAx.Toolbar.Visible = 'off';
                        % we need to tag axes here to keep track of order
                        % later
                        app.PanelAllTrials.Children(1).UserData = 2*i-1;  % Axes in Children are appended to top of the list!
                    else
                        ind = [app.PanelAllTrials.Children(:).UserData] == 2*i-1;
                        hAx = app.PanelAllTrials.Children(ind); 
                    end
                    if app.mainWin.UserData.figDefaults.normColMapByMax
                        scanpix.plot.plotRateMap(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{i}{ce_ind},hAx,'colmap',app.mainWin.UserData.figDefaults.rMapColMap,'nsteps',app.mainWin.UserData.figDefaults.rMapColNSteps,'cmapEdge',[0 maxVal]);
                    else
                        if isempty(hAx.Children)
                            scanpix.plot.plotRateMap(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{i}{ce_ind},hAx,'colmap',app.mainWin.UserData.figDefaults.rMapColMap,'nsteps',app.mainWin.UserData.figDefaults.rMapColNSteps);
                            axis(hAx,'square');
                            title(hAx,['Trial_' num2str(i)],'Interpreter','none');
                        else
                            hAx.Children(1).CData = app.mainWin.UserData.binnedRMaps{ds_ind}{i}{ce_ind};
                            hAx.CLim              = [0 app.mainWin.UserData.figDefaults.rMapColNSteps+1]; 
                        end 
                    end
                    % format axis
%                     axis(hAx,'square');
                    set(hAx,'xlim',[0.5 mapSz(2)+0.5],'ylim',[0.5 mapSz(1)+0.5]);
                    set(get(hAx,'xlabel'),'String',['peakFR=' num2str(max(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{i}{ce_ind}(:),[],'omitnan'),'%.1f')]);
                end
                
                if ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1})
                    if axCheck && ( isempty(app.PanelAllTrials.Children) || ~any([app.PanelAllTrials.Children(:).UserData] == 2*i) )
                        hAx = uiaxes(app.PanelAllTrials,'position',currPos - [0 120 0 0]);
                        app.PanelAllTrials.Children(1).UserData = 2*i;
                    else
                        ind =[app.PanelAllTrials.Children(:).UserData] == 2*i;
                        hAx = app.PanelAllTrials.Children(ind); 
                    end
                    if isempty(hAx.Children)
                        scanpix.plot.plotDirMap(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{i}{ce_ind}, hAx);
                    else
                        [meanR,meanDir,thetas,rhos] = scanpix.analysis.rayleighVect(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{i}{ce_ind});
                        [x,y] = pol2cart(meanDir,meanR);
                        hAx.Children(1).XData = [0 x];
                        hAx.Children(1).YData = [0 y];
                        [x,y] = pol2cart(thetas, rhos);
                        hAx.Children(2).XData = [x; x(1)];
                        hAx.Children(2).YData = [y; y(1)];
                    end
                    set(get(hAx,'xlabel'),'String',['peakFR=' num2str(max(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{i}{ce_ind},[],'omitnan'),'%.1f')]);
                end
                currPos(1) = sum(currPos([1 3]));
            end
        end
        
        function str = freezePlotsSel(app,mode,varargin)
            
            axisNames  = {'wfs',      'ac_shortLag', 'ac_longLag', 'spikepos',    'rmap',    'varmap',        'dmap',   'PanelAllTrials'};
            defaultStr = {'Waveforms','AC short Lag','AC long lag','Spike by Pos','Rate Map','Var. Data Plot','Dir Map','All Trials Panel'};
            switch mode
                case 'get'
                    ind = false(1,length(defaultStr));
                    for i = 1:length(defaultStr)
                        ind(i) = app.(axisNames{i}).UserData.plot;
                    end
                    if varargin{1}
                        str = defaultStr(ind);
                    else
                        str = defaultStr(~ind);
                    end
                case 'set'
                    for i = 1:length(varargin{1})
                        ind = strcmp(varargin{1}{i},defaultStr);
                        app.(axisNames{ind}).UserData.plot = ~app.(axisNames{ind}).UserData.plot;
                    end
                    str = {};
                case 'init'
                    for i = 1:length(axisNames)
                        app.(axisNames{i}).UserData.plot = true;
                    end 
            end   
        end
        
        function plot_sACs(app,event)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{tr_ind}); cla(app.varmap); app.varmap.Visible='On'; title(app.varmap,'Var. Data Plot'); return; end
            % plot
            if isempty(app.varmap.Children)
                imagesc(app.varmap,app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{tr_ind}{ce_ind}); colormap(app.varmap,app.mainWin.UserData.figDefaults.sACColMap);
                axis(app.varmap,'square');
                title(app.varmap,'Spatial AC');
                mapSz = size(app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{tr_ind}{ce_ind});
                set(app.varmap,'ydir','normal','xlim',[0 mapSz(2)],'ylim',[0 mapSz(1)],'xtick',[],'ytick',[]);
                set(get(app.varmap,'xlabel'),'String',' ','Visible','On');
                ylabel(app.varmap,'');
            else
                app.varmap.Children(1).CData = app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{tr_ind}{ce_ind};
                if strcmp(event.Source.Tag,'TrialSelBox') || strcmp(event.Source.Tag,'DSSelBox')
                    mapSz = size(app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{tr_ind}{ce_ind});
                    set(app.varmap,'ydir','normal','xlim',[0 mapSz(2)],'ylim',[0 mapSz(1)],'xtick',[],'ytick',[]);
                end
            end
        end        
             
        function plotCG(app,cellInd,binSz,lag,hAx)
            
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            
            if length(cellInd) == 1
                stB = 'AC';
            else
                stB = app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times{tr_ind}{cellInd(2)};
            end
            
            axis(hAx,'square');
            title(hAx,['AC ' num2str(lag*1000) 'ms']);
                       
            ac = scanpix.analysis.spk_crosscorr(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times{tr_ind}{cellInd(1)},...
                     stB,binSz,lag,app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).duration,'plot',hAx);
            
            set(hAx,'xlim',[-lag lag],'xtick',[-lag 0 lag],'xticklabel',[-lag*1000 0 lag*1000]);    
             
            if all(isnan(ac))
                hAx.YLim = [0 1];
            else
                hAx.YLim = [0 1.1*max(ac)];
            end     
            
            if length(cellInd) > 1; title(hAx,['CG ' num2str(lag*1000) 'ms']); end
 
        end
        
        function deletePrompt(app,~,event)
            if strcmpi(event.SelectedOption,'OK')
                delete(app);
            end
        end
        
        function plot_OVmaps(app,event)
            % lack of meta data format doesn't allow this yet!
%             if strcmp(app.mainWin.UserData.dataType,'dacq'); return; end
            
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            
            if ~isfield(app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind),'objectPos') || isempty(app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).objectPos) || isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.OV{tr_ind}); cla(app.varmap); app.varmap.Visible='On'; title(app.varmap,'Var. Data Plot'); return; end
            % plot
            if isempty(app.varmap.Children)
                imagesc(app.varmap,app.mainWin.UserData.dataObj{ds_ind}.maps.OV{tr_ind}{ce_ind});
                clim(app.varmap,[0 max(app.mainWin.UserData.dataObj{ds_ind}.maps.OV{tr_ind}{ce_ind}(:))+eps]);
                colormap(app.varmap,app.mainWin.UserData.figDefaults.OVcolMap);
                axis(app.varmap,'square');
                title(app.varmap,'Object Vect');
                mapSz = size(app.mainWin.UserData.dataObj{ds_ind}.maps.OV{tr_ind}{ce_ind});
                set(app.varmap,'ydir','normal','xlim',[0 mapSz(2)],'xtick',[0 mapSz(2)/2 mapSz(2)],'ylim',[0 mapSz(1)],'ytick',[0 mapSz(1)/4 mapSz(1)/2 mapSz(1)*0.75 mapSz(1)],'yticklabel',{'0','\pi/2','\pi','1.5\pi','2\pi'});
                set(get(app.varmap,'xlabel'),'String','Distance','Visible','On');
            else
                app.varmap.Children(1).CData = app.mainWin.UserData.dataObj{ds_ind}.maps.OV{tr_ind}{ce_ind};
                app.varmap.CLim = [0 max(app.mainWin.UserData.dataObj{ds_ind}.maps.OV{tr_ind}{ce_ind}(:))+eps];
                if strcmp(event.Source.Tag,'TrialSelBox') || strcmp(event.Source.Tag,'DSSelBox')
                    mapSz = size(app.mainWin.UserData.dataObj{ds_ind}.maps.OV{tr_ind}{ce_ind});
                    set(app.varmap,'ydir','normal','xlim',[0 mapSz(2)],'xtick',[0 mapSz(2)/2 mapSz(2)],'ylim',[0 mapSz(1)],'ytick',[0 mapSz(1)/4 mapSz(1)/2 mapSz(1)*0.75 mapSz(1)],'yticklabel',{'0','\pi/2','\pi','1.5\pi','2\pi'});
                end
            end
            
        end
         
        function plot_SpeedMaps(app)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.speed{tr_ind}); cla(app.varmap); app.varmap.Visible='On'; title(app.varmap,'Var. Data Plot'); return; end
            
            % plot
            if isempty(app.varmap.Children)
                scanpix.plot.plotSpeedMap(app.mainWin.UserData.dataObj{ds_ind}.maps.speed{tr_ind}{ce_ind,1},app.varmap,'maxspeed',app.mainWin.UserData.figDefaults.mapParams.speed.maxSpeed);
            else
                if app.mainWin.UserData.figDefaults.plotNormSpeedMap
                    app.varmap.Children(1).XData = [];
                    app.varmap.Children(1).YData = [];
                    app.varmap.Children(2).XData = 1:size(app.mainWin.UserData.dataObj{ds_ind}.maps.speed{tr_ind}{ce_ind,2},1);
                    app.varmap.Children(2).YData = app.mainWin.UserData.dataObj{ds_ind}.maps.speed{tr_ind}{ce_ind,2}(:,1);
                    yExt = [-0.1 1];
                else
                    confInt = [app.mainWin.UserData.dataObj{ds_ind}.maps.speed{tr_ind}{ce_ind,1}(:,2) + app.mainWin.UserData.dataObj{ds_ind}.maps.speed{tr_ind}{ce_ind,1}(:,3:4); NaN NaN];
                    app.varmap.Children(1).XData = [1:size(confInt,1),1:size(confInt,1)];
                    app.varmap.Children(1).YData = confInt(:);
                    app.varmap.Children(2).XData = 1:size(confInt,1)-1;
                    app.varmap.Children(2).YData = app.mainWin.UserData.dataObj{ds_ind}.maps.speed{tr_ind}{ce_ind,1}(:,2);
                    yExt = [min([0;confInt(:)]) max(confInt(:))];
                end
                
                if sum(app.mainWin.UserData.dataObj{ds_ind}.maps.speed{tr_ind}{ce_ind,1}(:,2)) ~= 0
                    set(app.varmap,'ylim',yExt,'ytick',[0 max(app.varmap.Children(2).YData)],...
                        'YTickLabel',{'0' sprintf('%.1f',max(app.varmap.Children(2).YData))},'xlim',[0 length(app.mainWin.UserData.dataObj{ds_ind}.maps.speed{tr_ind}{ce_ind,1}(:,1))+1],...
                        'xtick',[0 length(app.mainWin.UserData.dataObj{ds_ind}.maps.speed{tr_ind}{ce_ind,1}(:,1))+1],'xticklabel',[0 round(app.mainWin.UserData.figDefaults.mapParams.speed.maxSpeed)]);
                end
            end
            title(app.varmap,'Speed Map');
        end
        
        function [tempH,ind] = findFigs(~,pattern)
            tempH = findall(groot, 'Type', 'figure');
            ind = ~cellfun('isempty',regexp({tempH.Name},pattern,'once'));
            tempH = tempH(ind);
        end
        
        
        function hDialg = makePopUpWin(app)
            hDialg = figure('Position',[app.mainWin.Position(1)+app.mainWin.Position(3)/2-125 app.mainWin.Position(2)+app.mainWin.Position(4)/2-75 280 100],'Name','scaNpix','ToolBar','none','MenuBar',"none");
            uicontrol('Parent',hDialg,'Style','text','Position',[20 30 240 40],'String','I''m trying my best here but this might take a while...','FontSize',11,'FontWeight',"bold",'BackgroundColor','w');
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataType)
        
            % version check
            if verLessThan('matlab','9.5')
                error([app.mainWin.Name '-GUI: You need to have at least MATLAB R2018b! Go and get an upgrade Kumpel.']);
            end
            
            setDefaults(app,'gui');
            setDefaults(app,'fig');
            setDefaults(app,'maps');
            % set a few things programmatically
            app.MakeRMapsButton.Tag        = 'rate';
            app.MakeDMapsButton.Tag        = 'dir';
            app.varmapButton.Tag           = 'var';
            app.TrialsinDatasetListBox.Tag = 'TrialSelBox';
            app.SelectDatasetsListBox.Tag  = 'DSSelBox';
            app.CC_binSzEditField.Value    = app.mainWin.UserData.figDefaults.ac_bSz_shortLag;
            app.CC_lagEditField.Value      = app.mainWin.UserData.figDefaults.ac_lag_shortLag;
            
            freezePlotsSel(app,'init');

            app.mainWin.UserData.dataObj = {};
            app.mainWin.UserData.toggleCellSel = app.CheckBoxToggleSel.Value;
            app.mainWin.UserData.toggleMem     = {};

            if nargin == 1 || isempty(dataType)
                str = {'dacq','npix','nexus'};
                [select, loadCheck] = listdlg('PromptString','Select data type:','ListString',str,'ListSize',[150 50],'SelectionMode','Single');
                if ~loadCheck
                    delete(app);
                    return;
                end
                str = str{select};
            else
                str = dataType;
            end
            app.mainWin.Name =  ['scaNpix.' str];
            app.mainWin.UserData.dataType = str;
            
            if strcmp(str,'npix')
                app.PlotClusterSpaceButton.Enable  = 'Off'; % this is dacq only atm
                app.PlotClusterSpaceButton.Visible = 'Off'; % this is dacq only atm
                app.LoadWaveForms.Enable  = 'On';
                app.DACQMenu.Visible      = 'Off'; 
            end
            % this is npix only
            if strcmp(str,'dacq')
                app.LoadWaveForms.Visible    = 'Off';
                app.ByClustLabelMenu.Visible = 'Off'; 
                app.NeuropixelsMenu.Visible  = 'Off'; 
            end
            
            baseVarInfo = evalin('base', 'whos');
            ind = strcmp({baseVarInfo(:).class}, 'scanpix.ephys');
            varNames = {baseVarInfo(ind).name};
            tempType = cell(length(varNames),1);
            for i = 1:length(varNames)
                tempObj =  evalin('base', varNames{i}); 
                tempType{i} = tempObj.type;
            end
            varNames = varNames(strcmp(tempType, str));
            
            if isempty(varNames)                
                figure(app.mainWin);
                return;
            end
            % select what to import
            [select, loadCheck] = listdlg('PromptString','Import Data?','ListString',varNames,'ListSize',[150 50]);
            if ~loadCheck
                figure(app.mainWin);
                return;
            end
            varNames = varNames(select);
            % assign selected data to obj
            count = 1;
            for i = 1:length(varNames)
                tempObj =  evalin('base', varNames{i});
                if ~tempObj.loadFlag
                    continue;
                end
                app.mainWin.UserData.dataObj{count}    = tempObj;
                app.SelectDatasetsListBox.Items{count} = tempObj.dataSetName;
                app.mainWin.UserData.toggleMem{count}  = [];

                switch app.mainWin.UserData.dataType
                    case {'dacq','nexus'}
                        app.mainWin.UserData.cellIDStr{count} = strrep(string(strcat('c',cellstr(num2str(tempObj.cell_ID(:,1))),'t',cellstr(num2str(tempObj.cell_ID(:,2))))),' ','');
                    case 'npix'
                        app.mainWin.UserData.cellIDStr{count} = strrep(string(strcat('clu_',num2str(tempObj.cell_ID(:,1)))),' ','');
                end

                % need to re-do binned maps in case import came from GUI state
                for j = 1:length(app.mainWin.UserData.dataObj{count}.trialNames)
                    if ~isempty(app.mainWin.UserData.dataObj{count}.maps.rate{j})
                        app.mainWin.UserData.binnedRMaps{count}{j} = cellfun(@(x) scanpix.maps.binAnyRMap(x,'colmap',app.mainWin.UserData.figDefaults.rMapColMap,'nsteps',app.mainWin.UserData.figDefaults.rMapColNSteps),app.mainWin.UserData.dataObj{count}.maps.rate{j},'uni',0);
                    end
                end

                app.mainWin.UserData.Filter{count} = true(length(app.mainWin.UserData.cellIDStr{i}),1);
                count = count + 1;
            end
            %
            app.mainWin.UserData.currSelInd    = [1 1 1];  
            %
            setFieldsGUI(app,[]);
        end

        % Callback function: LoadDataButton, LoadfromRawMenu
        function LoadDataButtonPushed(app, event)
            if isempty(app.mainWin.UserData.dataObj)
                assignInd = 1;
            else
                assignInd = length(app.mainWin.UserData.dataObj) + 1;
            end
            
            tempObj = scanpix.ephys(app.mainWin.UserData.dataType,'ui');
            if isempty(tempObj.trialNames); return; end
            tempObj.load({'pos','spikes'});
            
            switch app.mainWin.UserData.dataType
                case {'dacq','nexus'}
                    if ~tempObj.loadFlag || isempty(tempObj.spikeData.spk_Times{1}); figure(app.mainWin); return; end
                    app.mainWin.UserData.cellIDStr{assignInd} = string(strcat('c',strip(cellstr(num2str(tempObj.cell_ID(:,1)))),'t',strip(cellstr(num2str(tempObj.cell_ID(:,2)))))); 
                case 'npix'
                    if ~tempObj.loadFlag || isempty(tempObj.spikeData.spk_Times{1}); figure(app.mainWin); return; end
                    app.mainWin.UserData.cellIDStr{assignInd} = strrep(string(strcat('clu_',num2str(tempObj.cell_ID(:,1)))),' ','');
            end
            
            app.mainWin.UserData.dataObj{assignInd}    = tempObj;

            % make sure name is unique
            ds_name = tempObj.dataSetName;
            c = 2;
            while any(strcmp(app.SelectDatasetsListBox.Items,ds_name))
                ds_name = [ds_name '_' num2str(c)]; %#ok<SAGROW>
                c = c+1;
            end
            app.SelectDatasetsListBox.Items{assignInd}          = ds_name;
            app.mainWin.UserData.dataObj{assignInd}.dataSetName = ds_name;
            app.mainWin.UserData.currSelInd                     = [length(app.mainWin.UserData.dataObj) 1 1];
            app.mainWin.UserData.Filter{assignInd}              = true(length(app.mainWin.UserData.cellIDStr{assignInd}),1);
            app.mainWin.UserData.toggleMem{assignInd}           = [];
            
            setFieldsGUI(app, event);
        end

        % Menu selected function: LoadfromGUIstateMenu
        function LoadfromGUIstateMenuSelected(app, event)
            [fname, path] = uigetfile(fullfile(app.mainWin.UserData.GUIDefaults.outputDir,['GUIState_' app.mainWin.UserData.dataType(9:end) '*.mat']),'Select GUI State Data');
            if ~ischar(fname); figure(app.mainWin); return; end
            h = makePopUpWin(app);
            figure(h);
            tmp = load(fullfile(path,fname));
            % this shouldn't really happen unless you actively try and trick the GUI
            if ( strcmp(app.mainWin.UserData.dataType,'dacq') && ~strcmp(tmp.objData{1}.type,'dacq') ) || ( strcmp(app.mainWin.UserData.dataType,'npix') && ~strcmp(tmp.objData{1}.type,'npix') )
                warning([app.mainWin.Name '-GUI:LoadFromGUIState: GUI and data type in GUIstate are incompatible. Imagine a rolling eyes emoji here...']);
                return
            end

            if isempty(app.mainWin.UserData.dataObj)
                assignInd = 1;
                app.mainWin.UserData.GUIDefaults = tmp.GUIDefaults;
            else
                assignInd = length(app.mainWin.UserData.dataObj) + 1;
%                 app.mainWin.UserData.dataObj{assignInd:assignInd+length(tmp.objData)-1} = tmp.objData;
            end
            
            app.mainWin.UserData.dataObj(assignInd:assignInd+length(tmp.objData)-1)    = tmp.objData(:);
            app.mainWin.UserData.cellIDStr(assignInd:assignInd+length(tmp.objData)-1)  = tmp.cellIDStr(:);
            app.SelectDatasetsListBox.Items                                            = vertcat(app.SelectDatasetsListBox.Items(:), cellfun(@(x) x.dataSetName,tmp.objData,'uni',0));
            app.mainWin.UserData.Filter(assignInd:assignInd+length(tmp.objData)-1)     = cellfun(@(x) true(length(x),1),tmp.cellIDStr,'uni',0);
            app.mainWin.UserData.toggleMem(assignInd:assignInd+length(tmp.objData)-1)  = {[]};
            
            % need to re-do the binned maps in case maps were saved in GUI state
            for i = assignInd:assignInd+length(tmp.objData)-1
                if ~isempty(app.mainWin.UserData.dataObj{i}.maps.rate{1})
                    for j = 1:length(app.mainWin.UserData.dataObj{i}.trialNames)
                        app.mainWin.UserData.binnedRMaps{i}{j} = cellfun(@(x) scanpix.maps.binAnyRMap(x,'colmap',app.mainWin.UserData.figDefaults.rMapColMap,'nsteps',app.mainWin.UserData.figDefaults.rMapColNSteps),app.mainWin.UserData.dataObj{i}.maps.rate{j},'uni',0);
                    end
                end
            end
            
            app.mainWin.UserData.currSelInd = [assignInd 1 1];
            %%% FILTER???
%             app.mainWin.UserData.dataObj{assignInd}.dataSetName = ds_name;
            setFieldsGUI(app, event);
            
            if exist('h', 'var'); close(h); end
        end

        % Menu selected function: batchloadMenu
        function batchloadMenuSelected(app, event)
            
            if isempty(app.mainWin.UserData.dataObj)
                assignInd = 1;
            else
                assignInd = length(app.mainWin.UserData.dataObj) + 1;
            end
            %
            rtn = scanpix.helpers.makeCustomUIDialogue({'path cribsheet','filename','group method','make rate maps','meta data (field names)'},{'','*.xlsx','exp','1',''});
            %
            dirStruct = dir(fullfile(rtn{1,2},rtn{2,2}));
            if isempty(dirStruct)
                warning('scaNpix::MainGUI::BatchLoad: No cribsheet found in %s', rtn{1,2});
                return
            elseif length(dirStruct) > 1
                [fname, pathStr] = uigetfile(fullfile(rtn{1,2},'*.xlsx'),'Please Select Cribsheet');
                if ~ischar (fname); return; end
                fullPathSheet = fullfile(pathStr,fname);
            else
                fullPathSheet = fullfile(dirStruct.folder,dirStruct.name);
            end
            %
            objData = scanpix.batchLoader(fullPathSheet, rtn{3,2}, app.mainWin.UserData.dataType, strsplit(rtn{5,2},','),'paramsmap',app.mainWin.UserData.figDefaults.mapParams);
            % load and assign
            for i = 1:length(objData)
                
                if ~isempty(objData{i})
                    tempObj = objData{i};
                else
                    continue
                end
                %
                switch app.mainWin.UserData.dataType
                    case {'dacq','nexus'}
                        app.mainWin.UserData.cellIDStr{assignInd} = string(strcat('c',strip(cellstr(num2str(tempObj.cell_ID(:,1)))),'t',strip(cellstr(num2str(tempObj.cell_ID(:,2))))));
                    case 'npix'
                        app.mainWin.UserData.cellIDStr{assignInd} = strrep(string(strcat('clu_',num2str(tempObj.cell_ID(:,1)))),' ','');
                end
                %
                app.mainWin.UserData.dataObj{assignInd}   = tempObj;
                app.mainWin.UserData.toggleMem{assignInd} = [];
                
                % make sure name is unique
                ds_name = tempObj.dataSetName;
                c = 2;
                while any(strcmp(app.SelectDatasetsListBox.Items,ds_name))
                    ds_name = [ds_name '_' num2str(c)]; %#ok<SAGROW>
                    c = c+1;
                end
                app.SelectDatasetsListBox.Items{assignInd}          = ds_name;
                app.mainWin.UserData.dataObj{assignInd}.dataSetName = ds_name;
                app.mainWin.UserData.Filter{assignInd}              = true(length(app.mainWin.UserData.cellIDStr{assignInd}),1);
                % add rate maps if requested
                if logical(rtn{4,2})
                    event.Source.Tag                                = 'rate';
                    app.mainWin.UserData.currSelInd                 = [assignInd 1 1];
                    if assignInd ~= 1
                        delete(app.PanelAllTrials.Children);
                    end
                    hgfeval({@MakeMapsButtonPushed,app,event});
                end
                %
                assignInd = assignInd + 1;
            end
            app.mainWin.UserData.currSelInd = [length(app.mainWin.UserData.dataObj) 1 1];
            %
            setFieldsGUI(app, event); 
        end

        % Value changed function: SelectDatasetsListBox
        function SelectDatasetsListBoxValueChanged(app, event)
            [~, ind] = ismember(app.SelectDatasetsListBox.Value, app.SelectDatasetsListBox.Items);
            if app.mainWin.UserData.toggleCellSel && ~isempty(app.mainWin.UserData.toggleMem{ind(1)})
                app.mainWin.UserData.currSelInd = [ind(1) 1 app.mainWin.UserData.toggleMem{ind(1)}(1)];
            else
                app.mainWin.UserData.currSelInd = [ind(1) 1 1];
            end
            setFieldsGUI(app, event);    
        end

        % Button pushed function: SelectAllDatasets
        function SelectAllDatasetsButtonPushed(app, event)
            app.SelectDatasetsListBox.Value = app.SelectDatasetsListBox.Items;
        end

        % Value changed function: TrialsinDatasetListBox
        function TrialsinDatasetListBoxValueChanged(app, event)
            [~, ind] = ismember(app.TrialsinDatasetListBox.Value, app.TrialsinDatasetListBox.Items);
            app.mainWin.UserData.currSelInd(2) = ind(1);
            setFieldsGUI(app, event);   
        end

        % Button pushed function: SelectAllTrials
        function SelectAllTrialsPushed(app, event)
            app.TrialsinDatasetListBox.Value = app.TrialsinDatasetListBox.Items;
        end

        % Value changed function: CellsinDatasetListBox
        function CellsinDatasetListBoxValueChanged(app, event)
            ds_ind = app.mainWin.UserData.currSelInd(1);
%             [~, ind] = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);
            [~, ind] = ismember(app.CellsinDatasetListBox.Value, app.mainWin.UserData.cellIDStr{ds_ind});
            app.mainWin.UserData.currSelInd(3) = ind(1);
            if app.mainWin.UserData.toggleCellSel; app.mainWin.UserData.toggleMem{ds_ind} = ind; end
            setFieldsGUI(app, event);   
        end

        % Button pushed function: SelectAllCells
        function SelectAllCellsPushed(app, event)
            app.CellsinDatasetListBox.Value = app.CellsinDatasetListBox.Items;
        end

        % Button pushed function: MakeDMapsButton, MakeRMapsButton, 
        % ...and 1 other component
        function MakeMapsButtonPushed(app, event)
            
            if isempty(app.mainWin.UserData.dataObj); warning('scaNpix::MainGUI::MakeMaps::There is currently no data loaded into the GUI, so stop pressing that button.'); return; end
            
            ds_ind = app.mainWin.UserData.currSelInd(1);
            binFlag = false;
            if strcmp(event.Source.Tag,'rate')
                mapType = 'rate';
                binFlag = true;
            elseif strcmp(event.Source.Tag,'dir')
                mapType = 'dir';
            elseif strcmp(event.Source.Tag,'var')
                switch lower(app.varMapList.Value)
                    case {'spatial ac','sac'}
                        h = makePopUpWin(app); figure(h);
                        app.mainWin.UserData.dataObj{ds_ind}.addMaps('sac',[],app.mainWin.UserData.figDefaults.mapParams);
                        setFieldsGUI(app,event);
                        if exist('h', 'var'); close(h); end
                        return;
                    case {'object vect','objvect'}
                        mapType = 'objVect';
                    case {'speed mod.','speed'}
                        mapType = 'speed';      
                end
            end
            app.mainWin.UserData.dataObj{ds_ind}.addMaps(mapType,[],app.mainWin.UserData.figDefaults.mapParams);
            if binFlag
                for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.maps.(mapType))
                    app.mainWin.UserData.binnedRMaps{ds_ind}{i} = cellfun(@(x) scanpix.maps.binAnyRMap(x,'colmap',app.mainWin.UserData.figDefaults.rMapColMap,'nsteps',app.mainWin.UserData.figDefaults.rMapColNSteps),app.mainWin.UserData.dataObj{ds_ind}.maps.(mapType){i},'uni',0);
                end
            end
            setFieldsGUI(app,event);
        end

        % Value changed function: varMapList
        function varMapListValueChanged(app, event)
            cla(app.varmap); title(app.varmap,'Var. Data Plot'); app.varmap.Visible='On';
            setFieldsGUI(app,event); 
        end

        % Button pushed function: LoadWaveForms
        function LoadWaveFormsPushed(app, event)
            % Note that a few parameters for waveform loading are hard coded here atm - nWaveforms, nSamples and nChannels. this is is keep size of 
            % of GUIState managable - undecided whether it makes sense to move these to the GUI params so user has dynamic control?
            nWaves = [];
            nSamples = 70; % final length will be nSamples+1; 
            percPrePeak = 0.5;
            nChans = 5; % should be odd integer
            
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);

            if ~isempty(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{1})
                selection = uiconfirm(app.mainWin,'Waveforms are already loaded. Do you want to continue?','Confirm Loading Waveforms','DefaultOption',2);
                if strcmp(selection,'Cancel'); return; end
            end
            
            if strcmp(app.mainWin.UserData.GUIDefaults.WF_loadMode,'file')

                fileCheck = nan(length(app.mainWin.UserData.dataObj{ds_ind}.dataPath),1);
                for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.dataPath)
                    fileCheck(i) = exist(fullfile(app.mainWin.UserData.dataObj{ds_ind}.dataPath{tr_ind},'waveForms_waves.mat'),'file');
                end
                
                if all(fileCheck == 2)
                    for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.dataPath)
                        
                        tempH = msgbox(sprintf('Loading waveforms for trial %s.\nThis window will close when loading is finished.',num2str(i)),'Please wait...','help');
                        % load
                        tmp = load(fullfile(app.mainWin.UserData.dataObj{ds_ind}.dataPath{i},'waveForms_waves.mat'));
                        f = fieldnames(tmp);
                        wf_in = tmp.(f{1});
                        try
                            tmp = load(fullfile(app.mainWin.UserData.dataObj{ds_ind}.dataPath{i},'waveForms_channelsDrift.mat'));
                        catch
                            tmp = load(fullfile(app.mainWin.UserData.dataObj{ds_ind}.dataPath{i},'waveForms_channelsRaw.mat'));
                        end
                        f = fieldnames(tmp);
                        chanIDs = tmp.(f{1});
                        
                        % select relevant data
                        wf_out = cell(length(wf_in),2);
                        for j = 1:length(wf_in)
                            % only grab 250 waveforms
                            if isempty(nWaves)
                                nWaves = size(wf_in{j,1},1);
                            end
                            spkInd  = ceil(linspace(1,size(wf_in{j,1},1),min(size(wf_in{j,1},1),nWaves)));
                            currWave = wf_in{j,1}(spkInd,:,:);
                            if isempty(currWave); continue; end
                            % some clusters will have less channels if e.g. they are located at bank boundaries
                            missChanInd = all(isnan(squeeze(currWave(1,:,:))),1);
                            currWave = currWave(:,:,~missChanInd);
                            % only select max chan +/- 2 channels
                            if size(currWave,3) > nChans
                                meanWFs = squeeze(mean(-currWave,1,'omitnan'));
%                                 [~, maxInd] = max(abs(min(meanWFs,[],1) - max(meanWFs,[],1)));
                                [~, maxInd] = max(max(meanWFs,[],1));
                                % need to check for special cases
                                if maxInd + floor(nChans/2) > size(currWave,3)
                                    endInd = size(currWave,3);
                                    startInd = endInd - nChans + 1;
                                elseif maxInd - floor(nChans/2) < 1
                                    startInd = 1;
                                    endInd = startInd + nChans - 1;
                                else
                                    startInd = maxInd-floor(nChans/2);
                                    endInd = maxInd+floor(nChans/2);
                                end
                                currWave = currWave(:,:,startInd:endInd);
                                wf_out{j,2} = chanIDs{j}(startInd:endInd);
                            end
                            % only select 41 wave form samples with 15 samples pre
                            % peak and 25 post peak
                            if size(currWave,2) > nSamples + 1
                                [~ ,maxInd] = min(squeeze(currWave(:,:,3)),[],2); % max sample loc per spike
                                startInd = round(median(maxInd) - percPrePeak*nSamples); % seem good enough to take overall median (or mean) max location across all waveforms
                                if startInd < 1; startInd = 1; end
                                currWave = currWave(:,startInd:startInd+nSamples,:);
                            end
                            wf_out{j,1} = currWave;
                        end
                        app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{i} = wf_out;
                        if exist('tempH', 'var')
                            delete(tempH);
                        end
                    end
                end
            elseif strcmp(app.mainWin.UserData.GUIDefaults.WF_loadMode,'cat')
                [fName, path] = uigetfile(fullfile(app.mainWin.UserData.dataObj{ds_ind}.dataPath{1},'*.dat;*.ap.bin'),'Select drift corrected file from KS clustering.');
                if isnumeric(path)
                    warning('scaNpix::MainGUI::LoadWaveforms::Loading waveform data aborted. I was really looking forward to having a look at these...');
                    return
                end
                app.mainWin.UserData.dataObj{ds_ind}.loadWaves('nwave',nWaves,'getnch',floor(nChans/2),'nsamp',nSamples,'prepeak',percPrePeak,'mode','cat','path2cat',fullfile(path,fName),'clu',app.mainWin.UserData.dataObj{ds_ind}.cell_ID(ce_ind,1));
            else
                warning('scaNpix::MainGUI::LoadWaveforms::Loading waveform data from a raw unprocessed binary isn''t recommended. Best to use the drift-corrected file instead.');
                app.mainWin.UserData.dataObj{ds_ind}.loadWaves('nwave',nWaves,'getnch',floor(nChans/2),'nsamp',nSamples,'prepeak',percPrePeak,'mode','single','clu',app.mainWin.UserData.dataObj{ds_ind}.cell_ID(ce_ind,1));
            end
            setFieldsGUI(app,event);
        end

        % Callback function: PlotAllMapsMenu, 
        % ...and 2 other components
        function PlotGroupsOfMaps(app, event)
        
            ds_ind = app.mainWin.UserData.currSelInd(1);

            tmp_mapType = {'rate','spike','dir','sACs','speed'}; % ADD LIN RATE MAPS AS OPTION? POS removed 

            if strcmp(event.Source.Text,'Plot Selection') || strcmp(event.Source.Text,'Plot All Maps')
                [select, loadCheck] = listdlg('PromptString','Select what type of Plots You Fancy:','ListString',tmp_mapType,'ListSize',[150 100]);
                if ~loadCheck
                    return;
                end
                tmp_mapType = tmp_mapType(select);
                if strcmp(event.Source.Text,'Plot Selection')
                    [~, tr_ind] = ismember(app.TrialsinDatasetListBox.Value, app.TrialsinDatasetListBox.Items);
                    [~, ce_ind] = ismember(app.CellsinDatasetListBox.Value, app.mainWin.UserData.cellIDStr{ds_ind});
                else
                    tr_ind = 1:length(app.TrialsinDatasetListBox.Items);
                    ce_ind = 1:length(app.mainWin.UserData.cellIDStr{ds_ind});
                end
            elseif strcmp(event.Source.Text,'Plot All U Got (better go && get a coffee...)')
                tr_ind = 1:length(app.TrialsinDatasetListBox.Items);
                ce_ind = 1:length(app.mainWin.UserData.cellIDStr{ds_ind});
            end

            data = {};
            mapType = {};
            for i = 1:length(tmp_mapType)
                if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.(tmp_mapType{i}){1})
                    fprintf('scaNpix::MainGUI::PlotSelection::You need to make %s maps before trying to plot them. Come on, really?\n',tmp_mapType{i});
                    continue
                end
                temp    = cellfun(@(x) x(ce_ind),app.mainWin.UserData.dataObj{ds_ind}.maps.(tmp_mapType{i})(tr_ind),'uni',0);
                data    = cat(3,data,temp);
                mapType = [mapType,tmp_mapType{i}]; %#ok<SAGROW>
            end
            scanpix.plot.mapsMultPlot(data,mapType,app.mainWin.UserData.cellIDStr{ds_ind}(ce_ind),'figname',['scaNpix::' app.mainWin.UserData.dataObj{ds_ind}.dataSetName '_multPlot_' tmp_mapType{i}]);
            figure(app.mainWin);
        end

        % Menu selected function: FreezeMenu
        function FreezeMenuSelected(app, event)
            str = freezePlotsSel(app,'get',true);
            if isempty(str); warning([app.mainWin.Name '-GUI:FreezeMenu:Updates for all plots are already frozen mate.']); return; end
            
            [select, loadCheck] = listdlg('PromptString','Select Which Plots You Want to Freeze:','ListString',str,'ListSize',[200 200],'OKString','Freeze');
            if loadCheck
                freezePlotsSel(app,'set',str(select));
            end
            figure(app.mainWin);
            
        end

        % Menu selected function: UnFreezeMenu
        function UnFreezeMenuSelected(app, event)
            str = freezePlotsSel(app,'get',false);
            if isempty(str); warning([app.mainWin.Name '-GUI:UnFreezeMenu:No plots to Unfreeze here pal.']); return; end

            [select, loadCheck] = listdlg('PromptString','Select Which Plots You Want to UnFreeze:','ListString',str,'ListSize',[200 200],'OKString','UnFreeze');
            if loadCheck
                freezePlotsSel(app,'set',str(select));
            end
            figure(app.mainWin);
            
        end

        % Menu selected function: GUIMenu
        function GUIMenuSelected(app, event)
            prompts = fieldnames(app.mainWin.UserData.GUIDefaults);
            defVals = struct2cell(app.mainWin.UserData.GUIDefaults);
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,defVals);
            if ~isempty(rtn)
                setDefaults(app,'gui',rtn(:,1),rtn(:,2));
            end
            figure(app.mainWin);
        end

        % Menu selected function: GeneralPlottingMenu
        function GeneralPlottingMenuSelected(app, event)
            prompts = fieldnames(app.mainWin.UserData.figDefaults);
            defVals = struct2cell(app.mainWin.UserData.figDefaults);
            ind     = strcmp(prompts,'mapParams');
            prompts = prompts(~ind);
            defVals = defVals(~ind);
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,defVals);
            if ~isempty(rtn)
                setDefaults(app,'fig',rtn(:,1),rtn(:,2));
                setFieldsGUI(app,event);
            end
        end

        % Menu selected function: DirMenu, GridcellpropscalcMenu, 
        % ...and 5 other components
        function MapsMenuSelected(app, event)
            switch event.Source.Text
                case 'Rate'
                    mapType = 'rate';
                case 'Dir'
                    mapType = 'dir';
                case 'Linear'
                    mapType = 'lin';
                case 'ObjectVect'
                    mapType = 'objVect';
                case 'Speed'
                    mapType = 'speed';
                case 'Grid cell props calc.'
                    mapType = 'gridProps';
                case 'sAC'
                    mapType = 'sac';
            end
            prompts = fieldnames(app.mainWin.UserData.figDefaults.mapParams.(mapType));
            defVals = struct2cell(app.mainWin.UserData.figDefaults.mapParams.(mapType));
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,defVals);
            if ~isempty(rtn)
                setDefaults(app,'maps',rtn(:,1),rtn(:,2),mapType);
            end
        end

        % Menu selected function: DeleteDatasetsMenu
        function DeleteDatasetsMenuSelected(app, event)
            [~, ind] = ismember(app.SelectDatasetsListBox.Value, app.SelectDatasetsListBox.Items);
            selInd = true(1,length(app.SelectDatasetsListBox.Items));
            selInd(ind) = false;
            if any(selInd)
                app.mainWin.UserData.dataObj     = app.mainWin.UserData.dataObj(selInd);
                app.SelectDatasetsListBox.Items  = app.SelectDatasetsListBox.Items(selInd);
                app.mainWin.UserData.cellIDStr   = app.mainWin.UserData.cellIDStr(selInd);
                app.mainWin.UserData.Filter      = app.mainWin.UserData.Filter(selInd);
                if isfield(app.mainWin.UserData,'binnedRMaps'); app.mainWin.UserData.binnedRMaps = app.mainWin.UserData.binnedRMaps(selInd); end
                
                app.mainWin.UserData.currSelInd = [1 1 1];
                setFieldsGUI(app,event);
            else
                [app.mainWin.UserData.dataObj,app.SelectDatasetsListBox.Items,app.TrialsinDatasetListBox.Items,app.CellsinDatasetListBox.Items] = deal({});
                [app.CurrentDatasetEditField.Value,app.TrialEditField.Value,app.CellEditField.Value] = deal(''); 
                
                cla(app.ac_shortLag); app.ac_shortLag.Visible='On'; 
                cla(app.ac_longLag);  app.ac_longLag.Visible='On'; 
                cla(app.wfs);         app.wfs.Visible='On';      
                cla(app.rmap);        app.rmap.Visible='On'; 
                cla(app.dmap);        app.dmap.Visible='On';     
                cla(app.spikepos);    app.spikepos.Visible='On';
                cla(app.varmap);      app.varmap.Visible='On';    app.varmap.Title.String = 'Var. Data Plot';  
                app.nSpikes.Value = 0; 
                app.amplitude.Value = 0; 
                if strcmp(app.mainWin.UserData.dataType,'npix'); app.cluLabel.Value = 'n/a'; end
                delete(app.PanelAllTrials.Children);
            end
        end

        % Menu selected function: DeleteTrialsMenu
        function DeleteTrialsMenuSelected(app, event)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            [~, ind] = ismember(app.TrialsinDatasetListBox.Value, app.TrialsinDatasetListBox.Items);
            delInd = false(1,length(app.TrialsinDatasetListBox.Items));
            delInd(ind) = true;
            
            if ~all(delInd)
                app.mainWin.UserData.dataObj{ds_ind}.deleteData('trials',app.mainWin.UserData.dataObj{ds_ind}.trialNames(delInd)); 
                app.TrialsinDatasetListBox.Items = app.mainWin.UserData.dataObj{ds_ind}.trialNames;
                app.mainWin.UserData.currSelInd(2:3) = [1 1];
                setFieldsGUI(app,event);
            else
                hgfeval({@DeleteDatasetsMenuSelected,app,event}); % delete dataset in case all trials were deleted 
            end
            
        end

        % Menu selected function: DeleteCellsMenu
        function DeleteCellsMenuSelected(app, event)
        
            if strcmp(app.RemoveFilterMenu.Enable,'On')
                warning('scaNpix::MainGUI::You can''t delete cells while also having a filter enabled, Undo the filtering first, then delete the cells. Easy!');
                return
            end
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            [~, ind] = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);
            selInd = false(1,length(app.CellsinDatasetListBox.Items));
            selInd(ind) = true;
            
            if any(selInd)
                % delete selected cells
                app.mainWin.UserData.dataObj{ds_ind}.deleteData('cells', selInd)
                app.mainWin.UserData.cellIDStr{ds_ind}   = app.mainWin.UserData.cellIDStr{ds_ind}(~selInd,:);
                app.CellsinDatasetListBox.Items          = app.mainWin.UserData.cellIDStr{ds_ind};
                app.mainWin.UserData.Filter{ds_ind}      = app.mainWin.UserData.Filter{ds_ind}(~selInd);
                app.mainWin.UserData.binnedRMaps{ds_ind} = cellfun(@(x) x(~selInd),app.mainWin.UserData.binnedRMaps{ds_ind},'uni',0);
                app.mainWin.UserData.currSelInd(3)       = 1;
                setFieldsGUI(app,event);
            else
                hgfeval({@DeleteDatasetsMenuSelected,app,event}); % delete dataset in case all cells were deleted 
            end
        end

        % Value changed function: CurrentDatasetEditField
        function CurrentDatasetEditFieldValueChanged2(app, event)
            [~, ind]                                            = ismember(app.SelectDatasetsListBox.Items, event.PreviousValue);
            app.SelectDatasetsListBox.Items{find(ind)}          = app.CurrentDatasetEditField.Value; %#ok<SAGROW>
            app.mainWin.UserData.dataObj{find(ind)}.dataSetName = app.CurrentDatasetEditField.Value; %#ok<SAGROW>  
        end

        % Menu selected function: plotWaveformMenu
        function plotWaveformMenuSelected(app, event)
            maxNChan2plot = 5;
            
            currDsSelInd = app.mainWin.UserData.currSelInd(1);
            currTrSelInd = app.mainWin.UserData.currSelInd(2);
            currCeSelInd = app.mainWin.UserData.currSelInd(3);
                        
            if isempty(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{1})
                warning('scaNpix::MainGUI::You need to load waveforms first before plotting them.... kinda obvious, isn''t it?');
                return;
            else
                % quite a bit of hard coded pixel values here
                figSz  = [170 475];
                plotSz = [50 100];
                screenSz = get(0,'ScreenSize');
                [~,ind] = findFigs(app,'WaveDist_clu_');
                hFig = figure('Name',['scaNpix::WaveDist_clu_' num2str(app.mainWin.UserData.dataObj{currDsSelInd}.cell_ID(currCeSelInd,1)) '_' num2str(sum(ind))],'Units','pixels','Position',[0.5*screenSz(3)-figSz(1)/2 0.5*screenSz(4)-figSz(2)/2 figSz]);
                
                idx    = ismember(app.mainWin.UserData.dataObj{currDsSelInd}.chanMap(currTrSelInd).chanMap,[app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{currTrSelInd}{currCeSelInd,2}]);
                coords = [app.mainWin.UserData.dataObj{currDsSelInd}.chanMap(currTrSelInd).xcoords(idx) app.mainWin.UserData.dataObj{currDsSelInd}.chanMap(currTrSelInd).ycoords(idx)];
                shifts = [diff(coords,[],1);0 0];
                
                currWave = app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{currTrSelInd}{currCeSelInd,1};
                
                xOffSet = [11 27 43 59; 15 45 75 105];
                yOffSet = figSz(2)-plotSz(2);
                % plot at best
                if length(coords) > 2*maxNChan2plot + 1
                   ind = ceil(length(coords)/2)-maxNChan2plot:ceil(length(coords)/2)+maxNChan2plot;
                else 
                   ind = 1:size(coords,1);
                end
                % this is v inefficient as essentially that will happen during plotWaveform call - but for proper scaling we need to know
                % max and min voltage of best channel
                meanWFs = squeeze(mean(-currWave,1),'omitnan');
                % [~, maxInd] = max(abs(min(meanWFs,[],1) - max(meanWFs,[],1)));
%                 axLims  = [min(min(-currWave(:,:,maxInd))) max(max(-currWave(:,:,maxInd)))];
                axLims  = [min(min(meanWFs)) max(max(meanWFs))];

                
                faceAlpha = .3;
                for i = ind                    
                    % plot a square to indicate electrode pos on shank - not really necessary but makes figure clearer
                    tempAx2 = axes(hFig,'units','pixel','Position',[xOffSet(2,xOffSet(1,:)==coords(i,1)) yOffSet+20 plotSz(1) 60]);
                    patch(tempAx2,[0 1 1 0],[0 0 1 1],[0.3 0.3 0.3],'FaceAlpha',faceAlpha);
                    % plot waveform
                    tempAx = axes(hFig,'units','pixel','Position',[xOffSet(2,xOffSet(1,:)==coords(i,1)) yOffSet plotSz]);
                    scanpix.plot.plotWaveform(-currWave(:,:,i),tempAx,'plotIndWFs',false);
                    set(tempAx,'ylim',axLims);
                    axis(tempAx,'off');
                   
                    axis(tempAx2,'square');
                    axis(tempAx2,'off');
%                     text(tempAx)
                    if coords(i,1) > 30
                        if faceAlpha == .3; faceAlpha = .1; else; faceAlpha = .3; end 
                    end 
                    yOffSet = yOffSet - shifts(i,2)*3;
                end
            end
        end

        % Button pushed function: PlotCGSelectionButton
        function PlotCGSelectionButtonPushed(app, event)

            % relevant indices
            ds_ind          = app.mainWin.UserData.currSelInd(1);
            tr_ind          = app.mainWin.UserData.currSelInd(2);
            [~, cellSelInd] = ismember(app.CellsinDatasetListBox.Value, app.mainWin.UserData.cellIDStr{ds_ind});  

            % select data
            spikeTimes = app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times{tr_ind}(cellSelInd,1);
            cellIDs    = app.CellsinDatasetListBox.Value;
            
            if app.PlotmapsCheckBox.Value
                if ~isempty(app.mainWin.UserData.binnedRMaps{ds_ind}{tr_ind})
                    pltMaps = true;
                    maps = app.mainWin.UserData.dataObj{ds_ind}.maps.rate{tr_ind}(cellSelInd);
                else
                    pltMaps = false; maps = {};
                    warning('scaNpix::MainGUI::If you want maps on your figure, you need to make them first...Rolling eyes emoji.');
                end
            else; pltMaps = false; maps = {};
            end

            % plot
            figName = ['scaNpix::CG_overview_' app.mainWin.UserData.dataObj{ds_ind}.dataSetName];
            scanpix.plot.plotCGsGroup(spikeTimes,app.CC_binSzEditField.Value,app.CC_lagEditField.Value,app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).duration,maps,'cellIDStr',cellIDs,'plotmaps',pltMaps,'figname',figName);
            
        end

        % Button pushed function: PlotWFSelectionButton
        function PlotWFSelectionButtonPushed(app, event)
            % relevant indices
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            tr_ind   = app.mainWin.UserData.currSelInd(2);
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind})
                warning('scaNpix::MainGUI::PlotWFSelection:You need to load waveforms first, before demanding a plot.');
                return
            end
            [~, cellSelInd] = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);  
            % select data
            waveforms = app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}(cellSelInd,1);
            cellIDs   = app.CellsinDatasetListBox.Value;
            tempH     = findFigs(app,'scaNpix::waveforms*');
            scanpix.plot.plotWFsGroup(waveforms,cellIDs,['scaNpix::waveforms_' num2str(length(tempH)+1)]);
        end

        % Button pushed function: PlotClusterSpaceButton
        function PlotClusterSpaceButtonPushed(app, event)
            % relevant indices
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            tr_ind   = app.mainWin.UserData.currSelInd(2);
            % select data
%             if strcmp(app.mainWin.UserData.dataType,'scanpix.dacq')
              tetrodes = unique(app.mainWin.UserData.dataObj{ds_ind}.cell_ID(:,2));
%             else
%                 warning('scaNpix::MainGUI::PlotClusterSpace:Not yet supported for neuropixel data');
%                 return;
%             end
            [select, loadCheck] = listdlg('PromptString','Select Tetrode For Plotting The Cluster Space:','ListString',strcat({'Tetrode_'},num2str(tetrodes)),'ListSize',[160 100]);
            if ~loadCheck
                warning('scaNpix::MainGUI::Aborted plotting the cluster space. Very disappointing.');
                return;
            else
                tetNum = tetrodes(select);
            end
            cellSelInd = app.mainWin.UserData.dataObj{ds_ind}.cell_ID(:,2) == tetNum;
            waveforms  = app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}(cellSelInd,1);
            scaleMax   = app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).fullscale(tetNum,:);
            cellIDs    = app.CellsinDatasetListBox.Items(cellSelInd);    
            scanpix.plot.plotCluSpace(waveforms,scaleMax,cellIDs,['scaNpix::CluSpace_Tet' num2str(tetNum)]);  
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            setFieldsGUI(app,event);            
        end

        % Close request function: mainWin
        function mainWinCloseRequest(app, event)
            uiconfirm(app.mainWin,'Close This Amazing GUI? Are you for real...','Close scaNpix GUI','CloseFcn',@(src,event)deletePrompt(app,src,event)); % needs 2017b
        end

        % Value changed function: CC_binSzEditField
        function CC_binSzEditFieldValueChanged(app, event)
            setFieldsGUI(app,event);
        end

        % Value changed function: CC_lagEditField
        function CC_lagEditFieldValueChanged(app, event)
            setFieldsGUI(app,event);
        end

        % Button pushed function: LineariseItButton
        function LineariseItButtonPushed(app, event)
            % gather info
            ds_ind   = app.mainWin.UserData.currSelInd(1);

            trackInd = find(~cellfun('isempty',regexp({app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(:).trialType},'\w*track')));
            % fail gracefully
            if isempty(trackInd); warning('scaNpix::mainGUI::LineariseItButtonPushed:No data found for linearisation in selected dataset. Better check that ''trialType'' contains the string ''track''.'); return; end

            % in case meta data is missing prompt user
            if any(cellfun('isempty',({app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(trackInd).trialType}))) || any(cellfun('isempty',({app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(trackInd).trackLength})))
                prompts     = [strcat(cellstr(app.mainWin.UserData.dataObj{ds_ind}.trialNames(trackInd)),' - track type');strcat(cellstr(app.mainWin.UserData.dataObj{ds_ind}.trialNames(trackInd)),' - track length')];
                defaultVals = [{app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(trackInd).trialType};{app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(trackInd).trackLength}];
                rtn         = scanpix.helpers.makeCustomUIDialogue(prompts(:), defaultVals(:),120);
                for i = 1:length(trackInd)
                    app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(trackInd(i)).trialType   = rtn{i+i-1,2};
                    app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(trackInd(i)).trackLength = rtn{i*2,2};
                end
            end

            %
            cla(app.lin_allCells);   app.lin_allCells.Visible   = 'On';
            cla(app.lin_rate);       app.lin_rate.Visible       = 'On';
            cla(app.lin_spikebypos); app.lin_spikebypos.Visible = 'On';
            % make maps
            app.mainWin.UserData.dataObj{ds_ind}.addMaps('lin',trackInd,app.mainWin.UserData.figDefaults.mapParams);
            %
            app.mainWin.UserData.currSelInd = [ds_ind trackInd(1) 1];
            setFieldsGUI(app,event);
        end

        % Value changed function: RundirectionDropDown
        function RundirectionDropDownValueChanged(app, event)
            setFieldsGUI(app,event);  
        end

        % Menu selected function: plotWaveDistonProbeMenu
        function plotWaveDistonProbeMenuSelected(app, event)
            
            ds_ind    = app.mainWin.UserData.currSelInd(1);
            tr_ind    = app.mainWin.UserData.currSelInd(2);

            [~, ind]  = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);
            
            if length(ind) == 1
                warning('scaNpix::MainGUI::You need to select at least 2 clusters to plot distribution on shank. You can do better than this.');
                return;
            end
            
            % mean waveforms
            tempWaves = app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind,1}(ind);
            meanWFs = cellfun(@(x) squeeze(mean(x,1),'omitnan'),tempWaves,'uni',0);
            [~, maxInd] = cellfun(@(x) max(abs(min(x,[],1) - max(x,[],1))),meanWFs,'uni',0);
            % max chan ID
            tempCh = cell2mat(cellfun(@(x,y) x(y), app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind,2}(ind), maxInd,'uni',0));
            
            chanMap   = [app.mainWin.UserData.dataObj{ds_ind}.chanMap(tr_ind).xcoords app.mainWin.UserData.dataObj{ds_ind}.chanMap(tr_ind).ycoords];
            cluCoords = [app.mainWin.UserData.dataObj{ds_ind}.chanMap(tr_ind).xcoords(tempCh) app.mainWin.UserData.dataObj{ds_ind}.cell_ID(ind,2)];
            % plot
            scanpix.plot.plotWaveDistByLocOnShank(meanWFs,cluCoords,chanMap);
        end

        % Menu selected function: RemoveFilterMenu
        function RemoveFilterMenuSelected(app, event)
            ds_ind                                 = app.mainWin.UserData.currSelInd(1);
            
            app.mainWin.UserData.Filter{ds_ind}    = true(length(app.mainWin.UserData.cellIDStr{ds_ind}),1);
            app.CellsinDatasetListBox.Items        = app.mainWin.UserData.cellIDStr{ds_ind};
            app.CellsinDatasetListBox.Value        = app.CellsinDatasetListBox.Items{1};
            app.mainWin.UserData.toggleMem{ds_ind} = [];
            app.mainWin.UserData.currSelInd(3)     = 1;
            
            setFieldsGUI(app,event);
        end

        % Callback function: ExporCurSelMenu, ExportFullMenu, 
        % ...and 2 other components
        function exportData(app, event)
            
            if ~isfolder(app.mainWin.UserData.GUIDefaults.outputDir)
                warning(['scaNpix::MainGUI::exportData: ''' app.mainWin.UserData.GUIDefaults.outputDir ''' is not a valid directory. Better change that in the GUI defaults.']);
                return;
            end
        
            if strcmp(event.EventName,'KeyPress') || strcmp(event.Source.Text,'Full') || strcmp(event.Source.Text,'Save Data') || strcmp(event.Source.Text,'Objects only')
                objData   = app.mainWin.UserData.dataObj(:);
                cellIDStr = app.mainWin.UserData.cellIDStr(:);
            elseif strcmp(event.Source.Text,'Current Selection') || strcmp(event.Source.Text,'Objects (Current Selection)')
                [~, ind]  = ismember(app.SelectDatasetsListBox.Value, app.SelectDatasetsListBox.Items);
                objData   = app.mainWin.UserData.dataObj(ind);
                cellIDStr = app.mainWin.UserData.cellIDStr(ind);
            end
            % save 
            GUIDefaults = app.mainWin.UserData.GUIDefaults;
            if strcmp(event.Source.Text,'Objects (Current Selection)')
                fName   = fullfile(app.mainWin.UserData.GUIDefaults.outputDir,['ObjData_' num2str(app.mainWin.UserData.GUIDefaults.filename_suffix) '.mat']);
            else
                fName   = fullfile(app.mainWin.UserData.GUIDefaults.outputDir,['GUIState_' num2str(app.mainWin.UserData.GUIDefaults.filename_suffix) '.mat']);
            end
            fNameAlt    = scanpix.helpers.checkSaveFile(fName);
            %
            if ~strcmp(fName,fNameAlt)
                [~,tmpFname,~] = fileparts(fName);
                selection = uiconfirm(app.mainWin,'This file already exists: Do you want to overwrite it?',['Overwrite ''' tmpFname ''' ?'],'Options',{'Overwrite','Save as New'},'DefaultOption',2);
                if strcmpi(selection,'save as new'); fName = fNameAlt; end
            end
            %
            h = makePopUpWin(app); figure(h);
            %
            if strcmp(event.Source.Text,'Objects (Current Selection)'); save(fName,'objData', '-v7.3'); else; save(fName,'objData','GUIDefaults','cellIDStr','-v7.3'); end
            %
            if exist('h', 'var'); close(h); end
            [~, tmpFname, ~] = fileparts(fName);
            fprintf('Saved current data as ''%s'' to %s\n',tmpFname,app.mainWin.UserData.GUIDefaults.outputDir)
        end

        % Menu selected function: borderscoreMenu, gridnessMenu_analysis, 
        % ...and 3 other components
        function getSpatialProps(app, event)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            %
            age    = nan(sum(app.mainWin.UserData.Filter{ds_ind}),length(app.mainWin.UserData.dataObj{ds_ind}.trialNames));
            % gather the appropriate data for filtering
            switch event.Source.Text
                case 'spatial info'
                    if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
                        app.mainWin.UserData.dataObj{ds_ind}.addMaps('rate',[],app.mainWin.UserData.figDefaults.mapParams);
                    end
                    %
                    vals = app.mainWin.UserData.dataObj{ds_ind}.getSpatialProps('si');
                    for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                        age(:,i) = repmat(app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(i).age,sum(app.mainWin.UserData.Filter{ds_ind}),1);
                    end
                    varName = [app.mainWin.UserData.dataObj{ds_ind}.dataSetName '_spatInfo'];
                case 'grid properties'
                    if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{1})
                        app.mainWin.UserData.dataObj{ds_ind}.addMaps('sac',[],app.mainWin.UserData.figDefaults.mapParams);
                    end
                    %
                    vals = app.mainWin.UserData.dataObj{ds_ind}.getSpatialProps('grid');
                    for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                        age(:,i) = repmat(app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(i).age,sum(app.mainWin.UserData.Filter{ds_ind}),1);
                    end
                    varName = [app.mainWin.UserData.dataObj{ds_ind}.dataSetName '_gridProps'];
%                     disp('Grid props output is nCells x [gridness wavelength orientation] x trials');
                case 'rayleigh vector length'
                    if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1})
                        app.mainWin.UserData.dataObj{ds_ind}.addMaps('dir',[],app.mainWin.UserData.figDefaults.mapParams);
                    end
                    %
                    vals = app.mainWin.UserData.dataObj{ds_ind}.getSpatialProps('rv');
                    for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                        age(:,i) = repmat(app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(i).age,sum(app.mainWin.UserData.Filter{ds_ind}),1);
                    end
                    varName = [app.mainWin.UserData.dataObj{ds_ind}.dataSetName '_RV'];
                case 'border score'
                    if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
                        app.mainWin.UserData.dataObj{ds_ind}.addMaps('rate',[],app.mainWin.UserData.figDefaults.mapParams);
                    end
                    %
                    vals = app.mainWin.UserData.dataObj{ds_ind}.getSpatialProps('bs');
                    for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                        age(:,i) = repmat(app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(i).age,sum(app.mainWin.UserData.Filter{ds_ind}),1);
                    end
                    varName = [app.mainWin.UserData.dataObj{ds_ind}.dataSetName '_BS'];
            end
            rtn = {app.mainWin.UserData.cellIDStr{ds_ind}(app.mainWin.UserData.Filter{ds_ind}),age,vals(app.mainWin.UserData.Filter{ds_ind},:);};
            % export to base workspace
            assignin('base',varName,rtn);
            fprintf('You can find your data as ''%s'' in the base workspace...\n',varName);
        end

        % Menu selected function: RestoreGUIdefaultsMenu
        function RestoreGUIdefaultsMenuSelected(app, event)
            setDefaults(app,'gui');
            setDefaults(app,'fig');
            setDefaults(app,'maps');
        end

        % Menu selected function: ByNSpikesMenu, gridnessMenu, 
        % ...and 2 other components
        function filterByValue(app, event)
            if isempty(app.mainWin.UserData.dataObj); return; end
            
            ds_ind    = app.mainWin.UserData.currSelInd(1);
            
            prompts = {'threshold';'direction';'trialIndex'};
            if strcmp(event.Source.Text,'spatial Info')
                vals = {0.4,'gt', 1};
            elseif strcmp(event.Source.Text,'gridness')
                vals = {0.3,'gt', 1};
            elseif strcmp(event.Source.Text,'rayleigh vector')
                vals = {0.5,'gt', 1};
            elseif strcmp(event.Source.Text,'By N Spikes')
                % by default we make the filter nSpikes > threshold in any trial in dataset
                trialInd = '';
                for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times)
                    trialInd = [trialInd num2str(i) '|']; %#ok<SAGROW>
                end
                vals = {75,'gt', trialInd(1:end-1)};
            end
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,vals);
            if isempty(rtn); return; end

            % in case more trials should be used to make filter
            if ~isnumeric(rtn{3,2})
                trialN   = sscanf(rtn{3,2},'%d%*c')';
                temp     = char(sscanf(rtn{3,2},'%*d%c'));
                if strcmp(temp(1),'&')
                    opFh = @and; 
                elseif strcmp(temp(1),'|')
                    opFh = @or; 
                else
                    warning('scaNpix::MainGUI::FilterData:You can only combine trials with ''&''s'' or ''|''s'' to make a composite filter. You can do it.');
                    return;
                end
            else
                trialN = rtn{3,2};
            end
            % fail gracefully
            if any(trialN > length(app.mainWin.UserData.dataObj{ds_ind}.trialNames) )
                warning('scaNpix::MainGUI::FilterData:Dataset only contains %i trials but you wanted to use trial #%i...',length(app.mainWin.UserData.dataObj{ds_ind}.trialNames),max(trialN));
                return;
            end
            
            % gather the appropriate data for filtering
            switch event.Source.Text
                case 'spatial Info'
                    %
                    if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
                        app.mainWin.UserData.dataObj{ds_ind}.addMaps('rate',[],app.mainWin.UserData.figDefaults.mapParams);
                    end
                    %
                    filtVals = nan(size(app.mainWin.UserData.dataObj{ds_ind}.cell_ID,1),length(trialN));
                    for i = 1:length(trialN) %1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                        filtVals(:,i) = scanpix.analysis.spatial_info(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{trialN(i)},app.mainWin.UserData.dataObj{ds_ind}.maps.pos{trialN(i)});
                    end
                    % if exist('h', 'var'); close(h); end
                case 'gridness'
                    h = makePopUpWin(app); figure(h);
                    %
                    if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{1})
                        app.mainWin.UserData.dataObj{ds_ind}.addMaps('sac',[],app.mainWin.UserData.figDefaults.mapParams);
                    end
                    %
                    filtVals = nan(size(app.mainWin.UserData.dataObj{ds_ind}.cell_ID,1),length(trialN));
                    prms      = fieldnames(app.mainWin.UserData.figDefaults.mapParams.gridProps)';
                    prms(2,:) = struct2cell(app.mainWin.UserData.figDefaults.mapParams.gridProps)'; 
                    for i = 1:length(trialN) % length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                        filtVals(:,i) = cell2mat(cellfun(@(x) max(x,[],2,'omitnan'),cellfun(@(x) scanpix.analysis.gridprops(x,prms{:}),app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{trialN(i)},'uni',0),'uni',0));
                    end
                    if exist('h', 'var'); close(h); end
                case 'rayleigh vector'
                    h = makePopUpWin(app); figure(h); 
                    %
                    if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1})
                        app.mainWin.UserData.dataObj{ds_ind}.addMaps('dir',[],app.mainWin.UserData.figDefaults.mapParams);
                    end
                    %
                    filtVals = nan(size(app.mainWin.UserData.dataObj{ds_ind}.cell_ID,1),length(trialN));
                    for i = 1:length(trialN) % 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                        filtVals(:,i) = cell2mat(cellfun(@(x,y) scanpix.analysis.rayleighVect(x),app.mainWin.UserData.dataObj{ds_ind}.maps.dir{trialN(i)},'uni',0));
                    end
                    if exist('h', 'var'); close(h); end
                case 'By N Spikes'
                    filtVals = nan(size(app.mainWin.UserData.dataObj{ds_ind}.cell_ID,1),length(trialN));
                    for i = 1:length(trialN) % length(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times)
                        filtVals(:,i) = cell2mat(cellfun(@(x) length(x),app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times{trialN(i)},'uni',0));
                    end
            end
            % make the filter
            for i = 1:length(trialN)
                if i == 1
                    tempFilt = scanpix.GUI.filterDataGUI(filtVals(:,i),rtn{1,2},'byValue',rtn{2,2});
                else
                    tempFilt = opFh(tempFilt,scanpix.GUI.filterDataGUI(filtVals(:,i),rtn{1,2},'byValue',rtn{2,2})); 
                end
            end
            %
            if ~any(app.mainWin.UserData.Filter{ds_ind} & tempFilt)
                warning('scaNpix::MainGUI::FilterData:The filter you so badly desire, doesn''t let any cell pass. We need to abort here.')
                return;
            end
            % apply to data
            app.mainWin.UserData.Filter{ds_ind} = app.mainWin.UserData.Filter{ds_ind} & tempFilt;
            app.CellsinDatasetListBox.Items     = app.mainWin.UserData.cellIDStr{ds_ind}(app.mainWin.UserData.Filter{ds_ind});
            app.CellsinDatasetListBox.Value     = app.CellsinDatasetListBox.Items{1};

            app.mainWin.UserData.currSelInd(3)  = 1;
            
            setFieldsGUI(app,event);  
        end

        % Menu selected function: ByClustLabelMenu
        function filterByClust(app, event)
            ds_ind    = app.mainWin.UserData.currSelInd(1);
            
            str = unique(app.mainWin.UserData.dataObj{ds_ind}.cell_Label);
            [select, loadCheck] = listdlg('PromptString','Select cluster label:','ListString',str,'ListSize',[160 100]);
            if ~loadCheck
                warning('scaNpix::MainGUI::Filtering aborted. What a waste...');
                return;
            else
                str = str(select);
            end
            
            tempFilt = scanpix.GUI.filterDataGUI(app.mainWin.UserData.dataObj{ds_ind}.cell_Label,str,'byLabel');
            
            if ~any(app.mainWin.UserData.Filter{ds_ind} & tempFilt)
                warning('scaNpix::MainGUI::FilterData:The filter you so badly desire, doesn''t let any cells pass. We need to abort here.')
                return;
            end
            
            app.mainWin.UserData.Filter{ds_ind} = app.mainWin.UserData.Filter{ds_ind} & tempFilt;
            app.CellsinDatasetListBox.Items     = app.mainWin.UserData.cellIDStr{ds_ind}(app.mainWin.UserData.Filter{ds_ind});
            app.CellsinDatasetListBox.Value     = app.CellsinDatasetListBox.Items{1};

            app.mainWin.UserData.currSelInd(3)  = 1;
             
            setFieldsGUI(app,event);
        end

        % Callback function: AllMenu, CustomSelectionMenu, 
        % ...and 1 other component
        function saveFigs(app, event)
        
            figH = findFigs(app,'scaNpix::*');
            if isempty(figH); return; end
            
            % filter selection if desired
            if strcmp(event.EventName,'KeyPress') || strcmp(event.Source.Text,'Custom Selection') || strcmp(event.Source.Text,'Export Figures') 
                str = {figH.Name};
                [select, loadCheck] = listdlg('PromptString','Select Figures For Saving As PDF:','ListString',str,'ListSize',[160 100]);
                if ~loadCheck
                    warning('scaNpix::MainGUI::Saving figures aborted. Would have been nice to keep those...');
                    figure(app.mainWin);
                    return;
                else
                    figH = figH(select);
                end
            end
            % save
            for i = 1:length(figH)
                ind         = strfind(figH(i).Name,'::');
                % make sure not to overwrite figures
                figName     = scanpix.helpers.checkSaveFile(fullfile(app.mainWin.UserData.GUIDefaults.outputDir,['scaNpix_' figH(i).Name(ind+2:end) '.pdf']));
                [~,figName] = fileparts(figName);
                scanpix.helpers.saveFigAsPDF(figH(i),figName,app.mainWin.UserData.GUIDefaults.outputDir);
            end

        end

        % Menu selected function: FilterbytimeMenu
        function FilterbytimeMenuSelected(app, event)
            ds_ind          = app.mainWin.UserData.currSelInd(1);
            [~, tr_ind]     = ismember(app.TrialsinDatasetListBox.Value, app.TrialsinDatasetListBox.Items);
            [~, cellSelInd] = ismember(app.CellsinDatasetListBox.Value, app.mainWin.UserData.cellIDStr{ds_ind});
            %
            prompts = {'map type','start time','end time','repeat?'};
            defVals  = {'rate',0,120,true};
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,defVals);
            %
            if rtn{4,2}
                nTimeSteps = floor(min([app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).duration]) / rtn{3,2});
            else
                nTimeSteps = 1;
            end
            %
            mapData = cell(1,nTimeSteps,length(tr_ind));
            headers = cell(nTimeSteps,length(tr_ind));
            for i = 1:length(tr_ind)
                
                [tmpMaps, time2plot] = scanpix.maps.makeMapTimeSeries(app.mainWin.UserData.dataObj{ds_ind},[rtn{2,2} rtn{3,2}],i,cellSelInd,'type',rtn{1,2},'rep',rtn{4,2});
                mapData(1,1:nTimeSteps,i) = tmpMaps(1:nTimeSteps);
                %
                for j = 1:nTimeSteps
                    headers{j,i}   = ['T' num2str(time2plot(j,1)) '-' num2str(time2plot(j,2)) 's-Trial' num2str(tr_ind(i))];
                end
            end
            % headers = horzcat(headers{:}');
            [~,ind] = findFigs(app,'MapTimeSeries');
            scanpix.plot.mapsMultPlot(mapData,repmat(rtn(1,2),1,i),app.CellsinDatasetListBox.Value,'figName',['scaNpix::MapTimeSeries_' num2str(sum(ind))],'cmap',app.mainWin.UserData.figDefaults.rMapColMap,'headers',headers);
            
        end

        % Menu selected function: addsinglefieldMenu
        function AddMetaDataMenuSelected(app, event)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            app.mainWin.UserData.dataObj{ds_ind}.addMetaData;
            figure(app.mainWin);
        end

        % Key press function: mainWin
        function mainWinKeyPress(app, event)
            % shortcuts with CTRL as modifier
            if strcmpi(event.Modifier,'control')
                if strcmpi(event.Key,'s')
                    hgfeval({@exportData,app,event});
                elseif strcmpi(event.Key,'f')
                    hgfeval({@saveFigs,app,event});
                elseif strcmpi(event.Key,'r')
                    event.Source.Tag = 'rate';
                    hgfeval({@MakeMapsButtonPushed,app,event});
                elseif strcmpi(event.Key,'d')
                    event.Source.Tag = 'dir';
                    hgfeval({@MakeMapsButtonPushed,app,event});
                elseif strcmpi(event.Key,'l')
                    hgfeval({@LoadDataButtonPushed,app,event});
                end
                return;
            end
            % other shortcuts
            ds_ind = app.mainWin.UserData.currSelInd(1);
            if strcmp(event.Key,'h')
                % display help
                hgfeval({@helpMenuSelected,app,event}); 
            elseif strcmp(event.Key,'uparrow')
                app.mainWin.UserData.currSelInd(3) = min([max([1,app.mainWin.UserData.currSelInd(3)-1]),size(app.mainWin.UserData.dataObj{ds_ind}.cell_ID,1)]);
                setFieldsGUI(app,event);
            elseif strcmp(event.Key,'downarrow')
                app.mainWin.UserData.currSelInd(3) = min([app.mainWin.UserData.currSelInd(3)+1,size(app.mainWin.UserData.dataObj{ds_ind}.cell_ID,1)]);
                setFieldsGUI(app,event);
            elseif strcmp(event.Key,'insert')
                app.mainWin.UserData.currSelInd(2) = min([max([1,app.mainWin.UserData.currSelInd(2)-1]),length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)]);
                app.mainWin.UserData.currSelInd(3) = 1;
                setFieldsGUI(app,event);
            elseif strcmp(event.Key,'delete')
                app.mainWin.UserData.currSelInd(2) = min([app.mainWin.UserData.currSelInd(2)+1,length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)]);
                app.mainWin.UserData.currSelInd(3) = 1;
                setFieldsGUI(app,event);
            elseif strcmp(event.Key,'pageup')
                app.mainWin.UserData.currSelInd(1) = min([max([1,app.mainWin.UserData.currSelInd(1)-1]),length(app.mainWin.UserData.dataObj)]);
                app.mainWin.UserData.currSelInd(2) = 1;
                app.mainWin.UserData.currSelInd(3) = 1;
                setFieldsGUI(app,event);
            elseif strcmp(event.Key,'pagedown')
                app.mainWin.UserData.currSelInd(1) = min([app.mainWin.UserData.currSelInd(1)+1,length(app.mainWin.UserData.dataObj)]);
                app.mainWin.UserData.currSelInd(2) = 1;
                app.mainWin.UserData.currSelInd(3) = 1;
                setFieldsGUI(app,event);
            end  
        end

        % Menu selected function: HelpMenu
        function helpMenuSelected(app, event)

            message = {'############### Shortcuts #################';...
                       '';...
                       'h - display help'
                       '';...
                       '############### CTRL+ #################';...
                       '';...
                       'CTRL+D - make Dir Maps';...
                       'CTRL+F - save figures';...
                       'CTRL+L - load data';...
                       'CTRL+M - make Rate Maps';...
                       'CTRL+S - save GUI state';...
                       '';...
                       '############### Scrolling ###############';...
                       '';...
                       'Up/Down - scroll through cells';...
                       'Ins/Del - scroll through trials';...
                       'PageUp/PageDown - scroll through datasets';...
                       'Wheelscroll - vertical scroll in figures';...
                       'CTRL+Wheelscroll - horizontal scroll in figures';...
                       '';...
                       '#########################################'};
            msgbox(message,'GUI Shortcuts');
        end

        % Menu selected function: reloadsortingresultsMenu
        function reloadsortingresultsMenuSelected(app, event)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            app.mainWin.UserData.dataObj{ds_ind}.load({'spikes','reload'});
            % need to change a few other bits
            app.mainWin.UserData.Filter{ds_ind}    = true(size(app.mainWin.UserData.dataObj{ds_ind}.cell_ID,1),1);
            app.mainWin.UserData.cellIDStr{ds_ind} = strrep(string(strcat('clu_',num2str(app.mainWin.UserData.dataObj{ds_ind}.cell_ID(:,1)))),' ','');
            app.CellsinDatasetListBox.Items        = app.mainWin.UserData.cellIDStr{ds_ind};
            setFieldsGUI(app,event);
        end

        % Menu selected function: BySelectionMenu
        function BySelectionMenuSelected(app, event)
            ds_ind    = app.mainWin.UserData.currSelInd(1);

            app.mainWin.UserData.Filter{ds_ind} = app.mainWin.UserData.Filter{ds_ind} & ismember(app.mainWin.UserData.cellIDStr{ds_ind},app.CellsinDatasetListBox.Value);
            app.CellsinDatasetListBox.Items     = app.mainWin.UserData.cellIDStr{ds_ind}(app.mainWin.UserData.Filter{ds_ind});
            app.CellsinDatasetListBox.Value     = app.CellsinDatasetListBox.Items{1};
            app.mainWin.UserData.currSelInd(3)  = 1;
             
            setFieldsGUI(app,event);
        end

        % Menu selected function: FilterbyDirMenu
        function FilterbyDirMenuSelected(app, event)
            ds_ind          = app.mainWin.UserData.currSelInd(1);
            [~, tr_ind]     = ismember(app.TrialsinDatasetListBox.Value, app.TrialsinDatasetListBox.Items);
            [~, cellSelInd] = ismember(app.CellsinDatasetListBox.Value, app.mainWin.UserData.cellIDStr{ds_ind});
            
            prompts = {'map type','start dir','bin size','repeat?'};
            defVals  = {'rate',0,45,'y'};
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,defVals);
            
            if strcmp(rtn{4,2},'y')
                %  
                dir2plot = [(rtn{2,2}:rtn{3,2}:rtn{2,2}+360-rtn{3,2})',(rtn{2,2}+rtn{3,2}:rtn{3,2}:rtn{2,2}+360)'];
                dir2plot(dir2plot>360) = dir2plot(dir2plot>360) - 360;
            elseif strcmp(rtn{4,2},'n')
                dir2plot = [rtn{2,2} rtn{2,2} + rtn{3,2}];
            else
                warning('scaNpix::MainGUI::PlotDirSeries: %s not allowed as input for field ''repeat?''',rtn{4,2});
                return;
            end
            dir2plot = dir2plot * pi/180; % radians

            prms = app.mainWin.UserData.figDefaults.mapParams.(rtn{1,2});
            prms.showWaitBar = false;
            %
            mapData = cell(1,size(dir2plot,1),length(tr_ind));
            headers = cell(1,size(dir2plot,1),length(tr_ind));
            for i = 1:length(tr_ind)

                if isKey(app.mainWin.UserData.dataObj{ds_ind}.params,'InterpPos2PosFs') && app.mainWin.UserData.dataObj{ds_ind}.params('InterpPos2PosFs')
                    sampleTimes = [];
                    prms.posFs  = app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind(i)).log.InterpPosFs;
                else
                    sampleTimes = app.mainWin.UserData.dataObj{ds_ind}.spikeData.sampleT{tr_ind(i)};
                end
                %
                if strcmp(rtn{1,2},'rate') && ~isempty( app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind(i)).envSize )
                    prms.envSize = app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind(i)).envSize ./ 100 .* app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind(i)).ppm; % in pixels
                end
                %
                dirRad = app.mainWin.UserData.dataObj{ds_ind}.posData.direction{tr_ind(i)} * pi/180;
                for j=1:size(dir2plot,1)
                    keepInd = false(size(app.mainWin.UserData.dataObj{ds_ind}.posData.XY{tr_ind(i)},1),1);
                    circDistsA = circ_dist(dirRad,dir2plot(j,1));
                    circDistsB = circ_dist(dirRad,dir2plot(j,2));
                    % roundabout way to define dirs but not sure bins that wrap around 0 would be accounted for otherwise
                    indA = circDistsA > 0 & circDistsA < rtn{3,2} * pi/180;
                    indB = circDistsB < 0 & -circDistsB < rtn{3,2} * pi/180;
                    keepInd(indA&indB) = true;
                    
                    tempSpeed = app.mainWin.UserData.dataObj{ds_ind}.posData.speed{tr_ind(i)};
                    tempSpeed(~keepInd) = NaN;
                    switch rtn{1,2}
                        case 'rate'
                            tempPos = app.mainWin.UserData.dataObj{ds_ind}.posData.XY{tr_ind(i)};
                            tempPos(~keepInd,:) = NaN;
                            mapData{1,j,i} = scanpix.maps.makeRateMaps(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times{tr_ind(i)}(cellSelInd),tempPos,sampleTimes,app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind(i)).ppm,tempSpeed,prms);
                        case 'dir'
                            % makes no sense really - display warning? % 
                        case 'speed'
                            % to do
                    end
                    headers{j,i}   = [num2str(round(dir2plot(j,1)*180/pi)) '-' num2str(round(dir2plot(j,2)*180/pi)) 'deg-Trial' num2str(tr_ind(i))];
                end
            end
            [~,ind] = findFigs(app,'MapDirSeries');
            scanpix.plot.mapsMultPlot(mapData,repmat(rtn(1,2),1,i),app.CellsinDatasetListBox.Value,'figName',['scaNpix::MapDirSeries_' num2str(sum(ind))],'cmap',app.mainWin.UserData.figDefaults.rMapColMap,'headers',headers); %
        end

        % Menu selected function: getnExpMenu
        function getnExpMenuSelected(app, event)
            
            if strcmp(app.mainWin.UserData.dataType,'dacq')
                warning('scaNpix::MainGUI::getNExp: Not yet supported for DACQ data. Go and add it and feel the love...');
                return
            end
            
            allAnimals   = cell2mat(cellfun(@(x) x.trialMetaData(1).animal,app.mainWin.UserData.dataObj,'uni',0))';
            unAllAnimals = unique(allAnimals);
            % select which animal to do
            [select, loadCheck] = listdlg('PromptString','Select for which dataset to get the N exposure:','ListString',num2str(unAllAnimals),'ListSize',[250 500],'SelectionMode','Single');
            if ~loadCheck
                figure(app.mainWin);
                return;
            else
                whatAnimal = unAllAnimals(select);
            end
            %
            selInd = find(ismember(allAnimals,whatAnimal));
            % ds_ind = app.mainWin.UserData.currSelInd(1);
            % trialTypes = cellfun(@(x) {x.trialMetaData(:).trialType},app.mainWin.UserData.dataObj(ismember(cellfun(@(x) x.dataSetName,app.mainWin.UserData.dataObj,'uni',0),app.SelectDatasetsListBox.Value)),'uni',0);
            trialTypes = cellfun(@(x) {x.trialMetaData(:).trialType},app.mainWin.UserData.dataObj(selInd),'uni',0);
            trialTypes = unique([trialTypes{:}]);
            rtn = scanpix.helpers.makeCustomUIDialogue(['parent dir',trialTypes],[app.mainWin.UserData.GUIDefaults.outputDir,num2cell(zeros(1,length(trialTypes)))],250);
            %
            h = makePopUpWin(app); figure(h);
            %
            dataOut = scanpix.npixUtils.getNExp(['r' num2str(whatAnimal)],rtn{1,2},'trialtype',rtn(2:end,1),'npre',[rtn{2:end,2}]);

            if isempty(dataOut)
                warning('scaNpix::MainGUI::getNExp: There was some issue with collecting the n of exposures. Sort it out and try again.');
                if exist('h', 'var'); close(h); end
                figure(app.mainWin);
                return;
            end
            % allow selection of multiple data sets
            for i = selInd'
                % tempDSInd = ismember(cellfun(@(x) x.dataSetName,app.mainWin.UserData.dataObj,'uni',0),app.SelectDatasetsListBox.Value{i});
                vals = dataOut(ismember(dataOut(:,1),strcat(app.mainWin.UserData.dataObj{i}.trialNames,app.mainWin.UserData.dataObj{i}.fileType)),4);
                trialInd = find(ismember(strcat(app.mainWin.UserData.dataObj{i}.trialNames,app.mainWin.UserData.dataObj{i}.fileType),dataOut(:,1)));
                app.mainWin.UserData.dataObj{i}.addMetaData('nExp',vals,trialInd);
            end
            if exist('h', 'var'); close(h); end
            figure(app.mainWin);
        end

        % Menu selected function: PlotGridPropsMenu
        function PlotGridPropsMenuSelected(app, event)
            
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{tr_ind})
                app.mainWin.UserData.dataObj{ds_ind}.addMaps('sac',tr_ind,app.mainWin.UserData.dataObj{ds_ind}.mapParams);
            end
            %
            scanpix.plot.plotGridProps(app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{tr_ind}{ce_ind},...
                                      'peakDetect', app.mainWin.UserData.dataObj{ds_ind}.mapParams.gridProps.peakDetect,...
                                      'zScoreThr', app.mainWin.UserData.dataObj{ds_ind}.mapParams.gridProps.zScoreThr,...
                                      'minPeakSz',app.mainWin.UserData.dataObj{ds_ind}.mapParams.gridProps.minPeakSz);
        end

        % Button pushed function: BatchMapsButton
        function BatchMapsButtonPushed(app, event)
            
            ind = find( ismember(app.SelectDatasetsListBox.Items, app.SelectDatasetsListBox.Value) );
            if isempty(ind)
                warning('scaNpix::mainGUI::batchMaps:No data selected. No maps will be created. Boring...');
                return;
            end
            %
            str = {'pos','rate','dir','lin','sac','objVect','speed'};
            [select, loadCheck] = listdlg('PromptString','Select what maps to make:','ListString',str,'ListSize',[160 100],'SelectionMode','Single');
            if ~loadCheck
                warning('scaNpix::mainGUI::batchMaps:No data selected. No maps will be created. Boring...');
                return;
            else
                mapType = str{select};
            end
            %
            if any(strcmp(mapType,{'sac','objVect','speed'}))
                event.Source.Tag     = 'var';
                app.varMapList.Value = app.varMapList.Items{strcmp(mapType,{'sac','objVect','speed'})};
            else
                event.Source.Tag = mapType;
            end
            %
            for i = ind 
                app.mainWin.UserData.currSelInd = [i 1 1];
                if ~strcmp(mapType,'lin')
                    hgfeval({@MakeMapsButtonPushed,app,event});
                else
                    hgfeval({@LineariseItButtonPushed,app,event});
                end

            end
            %
            app.mainWin.UserData.currSelInd = [ind(1) 1 1];
            setFieldsGUI(app,event);
        end

        % Value changed function: TrackLengthEditField
        function TrackLengthEditFieldValueChanged(app, event)
            app.mainWin.UserData.dataObj{app.mainWin.UserData.currSelInd(1)}.trialMetaData(app.mainWin.UserData.currSelInd(2)).trackLength = app.TrackLengthEditField.Value;
        end

        % Value changed function: TrackTypeEditField
        function TrackTypeEditFieldValueChanged(app, event)
            app.mainWin.UserData.dataObj{app.mainWin.UserData.currSelInd(1)}.trialMetaData(app.mainWin.UserData.currSelInd(2)).trialType = app.TrackTypeEditField.Value;
            warning('scaNpix::mainGUI::TrackTypeEditFieldValueChanged:Changed trial type for ''%s'' to ''%s''',app.mainWin.UserData.dataObj{app.mainWin.UserData.currSelInd(1)}.trialNames(app.mainWin.UserData.currSelInd(2)),app.TrackTypeEditField.Value);
        end

        % Menu selected function: DatasetsMenu
        function DatasetsMenuSelected(app, event)
            % reorder dataset list (e.g. when you have to reload a specific one and want to move it back to the chronological order)
            [select, loadCheck] = listdlg('PromptString','Select which dataset to move:','ListString',app.SelectDatasetsListBox.Items,'ListSize',[200 500],'SelectionMode','Single');
            if ~loadCheck
                figure(app.mainWin);
                return;
            else
                whatInd = select;
            end
            %
            [select, loadCheck] = listdlg('PromptString','Select below which dataset to move to:','ListString',{'start',app.SelectDatasetsListBox.Items{:}},'ListSize',[200 500],'SelectionMode','Single'); %#ok<SAGROW>
            if ~loadCheck
                figure(app.mainWin);
                return;
            else
                whereInd = select;
                if select > 1; select = select + 1; end
            end
            % re-order index
            if select == 1
                reorderInd = [whatInd,1:whatInd-1,whatInd+1:length(app.mainWin.UserData.dataObj)];
            elseif select > length(app.mainWin.UserData.dataObj)
                reorderInd = [1:whatInd-1,whatInd+1:length(app.mainWin.UserData.dataObj),whatInd];
            else
                reorderInd = [1:whereInd-1,whatInd,whereInd:whatInd-1,whatInd+1:length(app.mainWin.UserData.dataObj)];
            end
            % all these bits need reordering:
            app.mainWin.UserData.dataObj     = app.mainWin.UserData.dataObj(reorderInd);
            app.mainWin.UserData.Filter      = app.mainWin.UserData.Filter(reorderInd);
            app.mainWin.UserData.cellIDStr   = app.mainWin.UserData.cellIDStr(reorderInd);
            app.mainWin.UserData.binnedRMaps = app.mainWin.UserData.binnedRMaps(reorderInd);
            app.SelectDatasetsListBox.Items  = app.SelectDatasetsListBox.Items(reorderInd);
            %
            app.mainWin.UserData.currSelInd = [whereInd 1 1];
            setFieldsGUI(app,event);
        end

        % Menu selected function: GUIparamstoobjectsMenu
        function GUIparamstoobjectsMenuSelected(app, event)
            %
            for i=1:length(app.mainWin.UserData.dataObj)
                app.mainWin.UserData.dataObj{i}.mapParams = app.mainWin.UserData.figDefaults.mapParams;
            end
        end

        % Menu selected function: ByShuffleMenu
        function ByShuffleMenuSelected(app, event)
            
            ds_ind    = app.mainWin.UserData.currSelInd(1);
            %
            str = {'isGrid','isDir','isSpat','isBC'};
            [select, loadCheck] = listdlg('PromptString','Select cell type:','ListString',str,'ListSize',[160 100],'SelectionMode','Single');
            if ~loadCheck
                warning('scaNpix::mainGUI::ByShuffleMenuSelected:Filtering aborted. That is so lame!');
                return;
            else
                fieldNameStr = str{select};
            end

            %
            [fNames, dataDir] = uigetfile(fullfile(app.mainWin.UserData.GUIDefaults.outputDir,'*.mat'),'Select Matlab Table with shuffling classification.');
            if isnumeric(fNames)
                warning('scaNpix::mainGUI::ByShuffleMenuSelected:Loading of params container aborted!');
                return
            end
            %
            h = makePopUpWin(app); figure(h);
            % load data
            tmp   = load(fullfile(dataDir,fNames));
            tName = fieldnames(tmp);
            % filter
            selInd = tmp.(tName{1}).(fieldNameStr) & strcmp(app.mainWin.UserData.dataObj{ds_ind}.dataSetName,tmp.(tName{1}).dataset); % this might graceful fail  
            app.mainWin.UserData.Filter{ds_ind} = ismember(app.mainWin.UserData.dataObj{ds_ind}.cell_ID(:,1),tmp.(tName{1}).cellID(selInd,1));
            app.CellsinDatasetListBox.Items     = app.mainWin.UserData.cellIDStr{ds_ind}(app.mainWin.UserData.Filter{ds_ind});
            app.CellsinDatasetListBox.Value     = app.CellsinDatasetListBox.Items{1};
            app.mainWin.UserData.currSelInd(3)  = 1;
            %
            if exist('h', 'var'); close(h); end
            figure(app.mainWin);
        end

        % Value changed function: CheckBoxToggleSel
        function CheckBoxToggleSelValueChanged(app, event)
            ds_ind    = app.mainWin.UserData.currSelInd(1);
            % 
            app.mainWin.UserData.toggleCellSel = app.CheckBoxToggleSel.Value;
            if app.mainWin.UserData.toggleCellSel
                [~, app.mainWin.UserData.toggleMem{ds_ind}] = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);
            else
                app.mainWin.UserData.toggleMem(:) = {[]};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainWin = uifigure('Visible', 'off');
            app.mainWin.Name = 'UI Figure';
            app.mainWin.CloseRequestFcn = createCallbackFcn(app, @mainWinCloseRequest, true);
            app.mainWin.KeyPressFcn = createCallbackFcn(app, @mainWinKeyPress, true);
            app.FileMenu = uimenu(app.mainWin);
            app.FileMenu.Text = 'File';
            app.LoadDataMenu = uimenu(app.FileMenu);
            app.LoadDataMenu.Text = 'Load Data';
            app.LoadfromRawMenu = uimenu(app.LoadDataMenu);
            app.LoadfromRawMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadfromRawMenu.Text = 'from Raw';
            app.LoadfromGUIstateMenu = uimenu(app.LoadDataMenu);
            app.LoadfromGUIstateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromGUIstateMenuSelected, true);
            app.LoadfromGUIstateMenu.Text = 'from GUI state';
            app.batchloadMenu = uimenu(app.LoadDataMenu);
            app.batchloadMenu.MenuSelectedFcn = createCallbackFcn(app, @batchloadMenuSelected, true);
            app.batchloadMenu.Text = 'batch load';
            app.reloadsortingresultsMenu = uimenu(app.LoadDataMenu);
            app.reloadsortingresultsMenu.MenuSelectedFcn = createCallbackFcn(app, @reloadsortingresultsMenuSelected, true);
            app.reloadsortingresultsMenu.Text = 'reload sorting results';
            app.SaveDataMenu = uimenu(app.FileMenu);
            app.SaveDataMenu.Text = 'Save Data';
            app.ExportFullMenu = uimenu(app.SaveDataMenu);
            app.ExportFullMenu.MenuSelectedFcn = createCallbackFcn(app, @exportData, true);
            app.ExportFullMenu.Text = 'Full';
            app.ExporCurSelMenu = uimenu(app.SaveDataMenu);
            app.ExporCurSelMenu.MenuSelectedFcn = createCallbackFcn(app, @exportData, true);
            app.ExporCurSelMenu.Text = 'Current Selection';
            app.ObjectsCurrentSelectionMenu = uimenu(app.SaveDataMenu);
            app.ObjectsCurrentSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @exportData, true);
            app.ObjectsCurrentSelectionMenu.Text = 'Objects (Current Selection)';
            app.DeleteDataMenu = uimenu(app.FileMenu);
            app.DeleteDataMenu.Text = 'Delete Data';
            app.DeleteDatasetsMenu = uimenu(app.DeleteDataMenu);
            app.DeleteDatasetsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteDatasetsMenuSelected, true);
            app.DeleteDatasetsMenu.Text = 'Dataset(s)';
            app.DeleteTrialsMenu = uimenu(app.DeleteDataMenu);
            app.DeleteTrialsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteTrialsMenuSelected, true);
            app.DeleteTrialsMenu.Text = 'Trial(s)';
            app.DeleteCellsMenu = uimenu(app.DeleteDataMenu);
            app.DeleteCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCellsMenuSelected, true);
            app.DeleteCellsMenu.Text = 'Cell(s)';
            app.HelpMenu = uimenu(app.FileMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @helpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.SettingsMenu = uimenu(app.mainWin);
            app.SettingsMenu.Text = 'Settings';
            app.SetDefaultsMenu = uimenu(app.SettingsMenu);
            app.SetDefaultsMenu.Text = 'Set Defaults';
            app.GUIMenu = uimenu(app.SetDefaultsMenu);
            app.GUIMenu.MenuSelectedFcn = createCallbackFcn(app, @GUIMenuSelected, true);
            app.GUIMenu.Text = 'GUI';
            app.GeneralPlottingMenu = uimenu(app.SetDefaultsMenu);
            app.GeneralPlottingMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneralPlottingMenuSelected, true);
            app.GeneralPlottingMenu.Text = 'General Plotting';
            app.MapsMenu = uimenu(app.SetDefaultsMenu);
            app.MapsMenu.Text = 'Maps';
            app.RateMenu = uimenu(app.MapsMenu);
            app.RateMenu.MenuSelectedFcn = createCallbackFcn(app, @MapsMenuSelected, true);
            app.RateMenu.Text = 'Rate';
            app.DirMenu = uimenu(app.MapsMenu);
            app.DirMenu.MenuSelectedFcn = createCallbackFcn(app, @MapsMenuSelected, true);
            app.DirMenu.Text = 'Dir';
            app.LinearMenu = uimenu(app.MapsMenu);
            app.LinearMenu.MenuSelectedFcn = createCallbackFcn(app, @MapsMenuSelected, true);
            app.LinearMenu.Text = 'Linear';
            app.ObjectVectMenu = uimenu(app.MapsMenu);
            app.ObjectVectMenu.MenuSelectedFcn = createCallbackFcn(app, @MapsMenuSelected, true);
            app.ObjectVectMenu.Text = 'ObjectVect';
            app.SpeedMenu = uimenu(app.MapsMenu);
            app.SpeedMenu.MenuSelectedFcn = createCallbackFcn(app, @MapsMenuSelected, true);
            app.SpeedMenu.Text = 'Speed';
            app.sACMenu = uimenu(app.MapsMenu);
            app.sACMenu.MenuSelectedFcn = createCallbackFcn(app, @MapsMenuSelected, true);
            app.sACMenu.Text = 'sAC';
            app.GridcellpropscalcMenu = uimenu(app.SetDefaultsMenu);
            app.GridcellpropscalcMenu.MenuSelectedFcn = createCallbackFcn(app, @MapsMenuSelected, true);
            app.GridcellpropscalcMenu.Text = 'Grid cell props calc.';
            app.RestoreGUIdefaultsMenu = uimenu(app.SetDefaultsMenu);
            app.RestoreGUIdefaultsMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoreGUIdefaultsMenuSelected, true);
            app.RestoreGUIdefaultsMenu.Text = 'Restore GUI defaults';
            app.GUIparamstoobjectsMenu = uimenu(app.SetDefaultsMenu);
            app.GUIparamstoobjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @GUIparamstoobjectsMenuSelected, true);
            app.GUIparamstoobjectsMenu.Text = 'GUI params to objects';
            app.PauseUpdatesMenu = uimenu(app.SettingsMenu);
            app.PauseUpdatesMenu.Text = 'Pause Updates';
            app.FreezeMenu = uimenu(app.PauseUpdatesMenu);
            app.FreezeMenu.MenuSelectedFcn = createCallbackFcn(app, @FreezeMenuSelected, true);
            app.FreezeMenu.Text = 'Freeze';
            app.UnFreezeMenu = uimenu(app.PauseUpdatesMenu);
            app.UnFreezeMenu.MenuSelectedFcn = createCallbackFcn(app, @UnFreezeMenuSelected, true);
            app.UnFreezeMenu.Text = 'UnFreeze';
            app.DataMenu = uimenu(app.mainWin);
            app.DataMenu.Text = 'Data';
            app.AddMetaDataMenu = uimenu(app.DataMenu);
            app.AddMetaDataMenu.Text = 'Add MetaData';
            app.addsinglefieldMenu = uimenu(app.AddMetaDataMenu);
            app.addsinglefieldMenu.MenuSelectedFcn = createCallbackFcn(app, @AddMetaDataMenuSelected, true);
            app.addsinglefieldMenu.Text = 'add single field';
            app.getnExpMenu = uimenu(app.AddMetaDataMenu);
            app.getnExpMenu.MenuSelectedFcn = createCallbackFcn(app, @getnExpMenuSelected, true);
            app.getnExpMenu.Text = 'get nExp';
            app.FilterMenu = uimenu(app.DataMenu);
            app.FilterMenu.Text = 'Filter';
            app.ByClustLabelMenu = uimenu(app.FilterMenu);
            app.ByClustLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @filterByClust, true);
            app.ByClustLabelMenu.Text = 'By Clust Label';
            app.ByNSpikesMenu = uimenu(app.FilterMenu);
            app.ByNSpikesMenu.MenuSelectedFcn = createCallbackFcn(app, @filterByValue, true);
            app.ByNSpikesMenu.Text = 'By N Spikes';
            app.BySpatialPropsMenu = uimenu(app.FilterMenu);
            app.BySpatialPropsMenu.Text = 'By Spatial Props';
            app.spatialInfoMenu = uimenu(app.BySpatialPropsMenu);
            app.spatialInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @filterByValue, true);
            app.spatialInfoMenu.Text = 'spatial Info';
            app.gridnessMenu = uimenu(app.BySpatialPropsMenu);
            app.gridnessMenu.MenuSelectedFcn = createCallbackFcn(app, @filterByValue, true);
            app.gridnessMenu.Text = 'gridness';
            app.rayleighvectorMenu = uimenu(app.BySpatialPropsMenu);
            app.rayleighvectorMenu.MenuSelectedFcn = createCallbackFcn(app, @filterByValue, true);
            app.rayleighvectorMenu.Text = 'rayleigh vector';
            app.BySelectionMenu = uimenu(app.FilterMenu);
            app.BySelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @BySelectionMenuSelected, true);
            app.BySelectionMenu.Text = 'By Selection';
            app.ByShuffleMenu = uimenu(app.FilterMenu);
            app.ByShuffleMenu.MenuSelectedFcn = createCallbackFcn(app, @ByShuffleMenuSelected, true);
            app.ByShuffleMenu.Text = 'By Shuffle';
            app.CustomMenu = uimenu(app.FilterMenu);
            app.CustomMenu.Enable = 'off';
            app.CustomMenu.Text = 'Custom';
            app.RemoveFilterMenu = uimenu(app.FilterMenu);
            app.RemoveFilterMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveFilterMenuSelected, true);
            app.RemoveFilterMenu.Text = 'Remove Filter';
            app.ReorderMenu = uimenu(app.DataMenu);
            app.ReorderMenu.Text = 'Reorder';
            app.DatasetsMenu = uimenu(app.ReorderMenu);
            app.DatasetsMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasetsMenuSelected, true);
            app.DatasetsMenu.Text = 'Datasets';
            app.FiguresMenu = uimenu(app.mainWin);
            app.FiguresMenu.Text = 'Figures';
            app.DACQMenu = uimenu(app.FiguresMenu);
            app.DACQMenu.Text = 'DACQ';
            app.DatasetMenu = uimenu(app.FiguresMenu);
            app.DatasetMenu.Text = 'Dataset';
            app.PlotAllMapsMenu = uimenu(app.DatasetMenu);
            app.PlotAllMapsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotGroupsOfMaps, true);
            app.PlotAllMapsMenu.Text = 'Plot All Maps';
            app.PlotAllUGotbettergogetacoffeeMenu = uimenu(app.DatasetMenu);
            app.PlotAllUGotbettergogetacoffeeMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotGroupsOfMaps, true);
            app.PlotAllUGotbettergogetacoffeeMenu.Text = 'Plot All U Got (better go && get a coffee...)';
            app.NeuropixelsMenu = uimenu(app.FiguresMenu);
            app.NeuropixelsMenu.Text = 'Neuropixels';
            app.plotWaveformMenu = uimenu(app.NeuropixelsMenu);
            app.plotWaveformMenu.MenuSelectedFcn = createCallbackFcn(app, @plotWaveformMenuSelected, true);
            app.plotWaveformMenu.Text = 'plot Waveform';
            app.plotWaveDistonProbeMenu = uimenu(app.NeuropixelsMenu);
            app.plotWaveDistonProbeMenu.MenuSelectedFcn = createCallbackFcn(app, @plotWaveDistonProbeMenuSelected, true);
            app.plotWaveDistonProbeMenu.Text = 'plot Wave Dist on Probe';
            app.MapsMenu_Figures = uimenu(app.FiguresMenu);
            app.MapsMenu_Figures.Text = 'Maps';
            app.FilterbytimeMenu = uimenu(app.MapsMenu_Figures);
            app.FilterbytimeMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterbytimeMenuSelected, true);
            app.FilterbytimeMenu.Text = 'Filter by time';
            app.FilterbyDirMenu = uimenu(app.MapsMenu_Figures);
            app.FilterbyDirMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterbyDirMenuSelected, true);
            app.FilterbyDirMenu.Text = 'Filter by Dir';
            app.PlotGridPropsMenu = uimenu(app.MapsMenu_Figures);
            app.PlotGridPropsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotGridPropsMenuSelected, true);
            app.PlotGridPropsMenu.Text = 'Plot Grid Props';
            app.SaveasPDFMenu = uimenu(app.FiguresMenu);
            app.SaveasPDFMenu.Text = 'Save as PDF';
            app.AllMenu = uimenu(app.SaveasPDFMenu);
            app.AllMenu.MenuSelectedFcn = createCallbackFcn(app, @saveFigs, true);
            app.AllMenu.Text = 'All';
            app.CustomSelectionMenu = uimenu(app.SaveasPDFMenu);
            app.CustomSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @saveFigs, true);
            app.CustomSelectionMenu.Text = 'Custom Selection';
            app.AnalysisMenu = uimenu(app.mainWin);
            app.AnalysisMenu.Text = 'Analysis';
            app.SpatialpropsMenu = uimenu(app.AnalysisMenu);
            app.SpatialpropsMenu.Text = 'Spatial props';
            app.gridnessMenu_analysis = uimenu(app.SpatialpropsMenu);
            app.gridnessMenu_analysis.MenuSelectedFcn = createCallbackFcn(app, @getSpatialProps, true);
            app.gridnessMenu_analysis.Text = 'grid properties';
            app.spatialinfoMenu_analysis = uimenu(app.SpatialpropsMenu);
            app.spatialinfoMenu_analysis.MenuSelectedFcn = createCallbackFcn(app, @getSpatialProps, true);
            app.spatialinfoMenu_analysis.Text = 'spatial info';
            app.rayleighvectorlengthMenu_analysis = uimenu(app.SpatialpropsMenu);
            app.rayleighvectorlengthMenu_analysis.MenuSelectedFcn = createCallbackFcn(app, @getSpatialProps, true);
            app.rayleighvectorlengthMenu_analysis.Text = 'rayleigh vector length';
            app.borderscoreMenu = uimenu(app.SpatialpropsMenu);
            app.borderscoreMenu.MenuSelectedFcn = createCallbackFcn(app, @getSpatialProps, true);
            app.borderscoreMenu.Text = 'border score';
            app.spatialcorrelationMenu = uimenu(app.SpatialpropsMenu);
            app.spatialcorrelationMenu.MenuSelectedFcn = createCallbackFcn(app, @getSpatialProps, true);
            app.spatialcorrelationMenu.Enable = 'off';
            app.spatialcorrelationMenu.Text = 'spatial correlation';
            app.TrialsinDatasetListBoxLabel = uilabel(app.mainWin);
            app.TrialsinDatasetListBoxLabel.Text = {'Trials'; 'in'; 'Dataset'};
            app.TrialsinDatasetListBox = uilistbox(app.mainWin);
            app.TrialsinDatasetListBox.Items = {};
            app.TrialsinDatasetListBox.Multiselect = 'on';
            app.TrialsinDatasetListBox.ValueChangedFcn = createCallbackFcn(app, @TrialsinDatasetListBoxValueChanged, true);
            app.TrialsinDatasetListBox.Value = {};
            app.CellsinDatasetListBoxLabel = uilabel(app.mainWin);
            app.CellsinDatasetListBoxLabel.Text = {'Cells'; 'in'; 'Dataset'};
            app.CellsinDatasetListBox = uilistbox(app.mainWin);
            app.CellsinDatasetListBox.Items = {};
            app.CellsinDatasetListBox.Multiselect = 'on';
            app.CellsinDatasetListBox.ValueChangedFcn = createCallbackFcn(app, @CellsinDatasetListBoxValueChanged, true);
            app.CellsinDatasetListBox.Value = {};
            app.SaveDataButton = uibutton(app.mainWin, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @exportData, true);
            app.SaveDataButton.Text = 'Save Data';
            app.TabGroup = uitabgroup(app.mainWin);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.wfs = uiaxes(app.OverviewTab);
            title(app.wfs, 'Waveform')
            xlabel(app.wfs, 'time (ms)')
            app.wfs.XTick = [];
            app.wfs.YTick = [];
            app.ac_shortLag = uiaxes(app.OverviewTab);
            xlabel(app.ac_shortLag, 'time (ms)')
            app.ac_shortLag.XLim = [-0.02 0.02];
            app.ac_shortLag.XTick = [];
            app.ac_shortLag.XTickLabel = '';
            app.ac_shortLag.YTick = [];
            app.spikepos = uiaxes(app.OverviewTab);
            title(app.spikepos, 'Spike Pos')
            app.spikepos.XTick = [];
            app.spikepos.YTick = [];
            app.rmap = uiaxes(app.OverviewTab);
            title(app.rmap, 'Rate Map')
            app.rmap.XTick = [];
            app.rmap.YTick = [];
            app.varmap = uiaxes(app.OverviewTab);
            title(app.varmap, 'Var. Data Plot')
            app.varmap.XTick = [];
            app.varmap.YTick = [];
            app.dmap = uiaxes(app.OverviewTab);
            title(app.dmap, 'Dir Map')
            app.dmap.XTick = [];
            app.dmap.YTick = [];
            app.ac_longLag = uiaxes(app.OverviewTab);
            xlabel(app.ac_longLag, 'time (ms)')
            app.ac_longLag.XLim = [-0.02 0.02];
            app.ac_longLag.XTick = [];
            app.ac_longLag.XTickLabel = '';
            app.ac_longLag.YTick = [];
            app.nSpikesTxt = uieditfield(app.OverviewTab, 'text');
            app.nSpikesTxt.Editable = 'off';
            app.nSpikesTxt.Value = 'nSpikes:';
            app.nSpikes = uieditfield(app.OverviewTab, 'numeric');
            app.nSpikes.Editable = 'off';
            app.amplitudeTxt = uieditfield(app.OverviewTab, 'text');
            app.amplitudeTxt.Editable = 'off';
            app.amplitudeTxt.Value = 'amplitude:';
            app.isodTxt = uieditfield(app.OverviewTab, 'text');
            app.isodTxt.Editable = 'off';
            app.isodTxt.Value = 'iso D:';
            app.lratioTxt = uieditfield(app.OverviewTab, 'text');
            app.lratioTxt.Editable = 'off';
            app.lratioTxt.Value = 'L-ratio:';
            app.amplitude = uieditfield(app.OverviewTab, 'numeric');
            app.amplitude.Editable = 'off';
            app.lratio = uieditfield(app.OverviewTab, 'numeric');
            app.lratio.Editable = 'off';
            app.isod = uieditfield(app.OverviewTab, 'numeric');
            app.isod.Editable = 'off';
            app.MakeRMapsButton = uibutton(app.OverviewTab, 'push');
            app.MakeRMapsButton.ButtonPushedFcn = createCallbackFcn(app, @MakeMapsButtonPushed, true);
            app.MakeRMapsButton.Text = 'Make Maps';
            app.varmapButton = uibutton(app.OverviewTab, 'push');
            app.varmapButton.ButtonPushedFcn = createCallbackFcn(app, @MakeMapsButtonPushed, true);
            app.varmapButton.Text = 'Make Plots';
            app.LoadWaveForms = uibutton(app.OverviewTab, 'push');
            app.LoadWaveForms.ButtonPushedFcn = createCallbackFcn(app, @LoadWaveFormsPushed, true);
            app.LoadWaveForms.Enable = 'off';
            app.LoadWaveForms.Text = {'Load '; 'Waveforms'};
            app.MakeDMapsButton = uibutton(app.OverviewTab, 'push');
            app.MakeDMapsButton.ButtonPushedFcn = createCallbackFcn(app, @MakeMapsButtonPushed, true);
            app.MakeDMapsButton.Text = 'Make Maps';
            app.PanelAllTrials = uipanel(app.OverviewTab);
            app.SelectPlotstyleLabel = uilabel(app.OverviewTab);
            app.SelectPlotstyleLabel.Text = 'Select Plotstyle';
            app.varMapList = uilistbox(app.OverviewTab);
            app.varMapList.Items = {'Spatial AC', 'Object Vect', 'Speed Mod.'};
            app.varMapList.ValueChangedFcn = createCallbackFcn(app, @varMapListValueChanged, true);
            app.varMapList.Value = 'Spatial AC';
            app.cluLabelTxt = uieditfield(app.OverviewTab, 'text');
            app.cluLabelTxt.Editable = 'off';
            app.cluLabelTxt.Value = 'clust. label: ';
            app.cluLabel = uieditfield(app.OverviewTab, 'text');
            app.cluLabel.Value = 'n/a';
            app.CompareCellsTab = uitab(app.TabGroup);
            app.CompareCellsTab.Title = 'Compare Cells';
            app.ac1 = uiaxes(app.CompareCellsTab);
            app.ac1.XLim = [-0.02 0.02];
            app.ac1.XTick = [];
            app.ac1.XTickLabel = '';
            app.ac1.YTick = [];
            app.cg2_3 = uiaxes(app.CompareCellsTab);
            app.cg2_3.XLim = [-0.02 0.02];
            app.cg2_3.XTick = [];
            app.cg2_3.XTickLabel = '';
            app.cg2_3.YTick = [];
            app.cg1_3 = uiaxes(app.CompareCellsTab);
            app.cg1_3.XLim = [-0.02 0.02];
            app.cg1_3.XTick = [];
            app.cg1_3.XTickLabel = '';
            app.cg1_3.YTick = [];
            app.ac2 = uiaxes(app.CompareCellsTab);
            app.ac2.XLim = [-0.02 0.02];
            app.ac2.XTick = [];
            app.ac2.XTickLabel = '';
            app.ac2.YTick = [];
            app.cg1_2 = uiaxes(app.CompareCellsTab);
            app.cg1_2.XLim = [-0.02 0.02];
            app.cg1_2.XTick = [];
            app.cg1_2.XTickLabel = '';
            app.cg1_2.YTick = [];
            app.ac3 = uiaxes(app.CompareCellsTab);
            app.ac3.XLim = [-0.02 0.02];
            app.ac3.XTick = [];
            app.ac3.XTickLabel = '';
            app.ac3.YTick = [];
            app.wf3 = uiaxes(app.CompareCellsTab);
            app.wf3.XLim = [-0.02 0.02];
            app.wf3.XTick = [];
            app.wf3.XTickLabel = '';
            app.wf3.YTick = [];
            app.wf1 = uiaxes(app.CompareCellsTab);
            app.wf1.XLim = [-0.02 0.02];
            app.wf1.XTick = [];
            app.wf1.XTickLabel = '';
            app.wf1.YTick = [];
            app.wf2 = uiaxes(app.CompareCellsTab);
            app.wf2.XLim = [-0.02 0.02];
            app.wf2.XTick = [];
            app.wf2.XTickLabel = '';
            app.wf2.YTick = [];
            app.CC_cell_ID1 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_cell_ID1.Editable = 'off';
            app.CC_cell_ID2 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_cell_ID2.Editable = 'off';
            app.binSzEditFieldLabel = uilabel(app.CompareCellsTab);
            app.binSzEditFieldLabel.Text = 'binSz';
            app.CC_binSzEditField = uieditfield(app.CompareCellsTab, 'numeric');
            app.CC_binSzEditField.Limits = [0 Inf];
            app.CC_binSzEditField.ValueChangedFcn = createCallbackFcn(app, @CC_binSzEditFieldValueChanged, true);
            app.lagEditFieldLabel = uilabel(app.CompareCellsTab);
            app.lagEditFieldLabel.Text = 'lag';
            app.CC_lagEditField = uieditfield(app.CompareCellsTab, 'numeric');
            app.CC_lagEditField.Limits = [0 Inf];
            app.CC_lagEditField.ValueChangedFcn = createCallbackFcn(app, @CC_lagEditFieldValueChanged, true);
            app.CC_cell_ID3 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_cell_ID3.Editable = 'off';
            app.CC_cell_ID2_2 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_cell_ID2_2.Editable = 'off';
            app.CC_cell_ID3_3 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_cell_ID3_3.Editable = 'off';
            app.TextArea = uitextarea(app.CompareCellsTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Correlogram'; 'props'};
            app.PlotCGSelectionButton = uibutton(app.CompareCellsTab, 'push');
            app.PlotCGSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCGSelectionButtonPushed, true);
            app.PlotCGSelectionButton.Text = 'Plot CG Selection';
            app.PlotWFSelectionButton = uibutton(app.CompareCellsTab, 'push');
            app.PlotWFSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWFSelectionButtonPushed, true);
            app.PlotWFSelectionButton.Text = 'Plot WF Selection';
            app.PlotClusterSpaceButton = uibutton(app.CompareCellsTab, 'push');
            app.PlotClusterSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotClusterSpaceButtonPushed, true);
            app.PlotClusterSpaceButton.Text = 'Plot Cluster Space';
            app.CC_cell_ID1_1 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_cell_ID1_1.Editable = 'off';
            app.CC_maxChanCell1 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_maxChanCell1.Editable = 'off';
            app.CC_maxChanCell2 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_maxChanCell2.Editable = 'off';
            app.CC_maxChanCell3 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_maxChanCell3.Editable = 'off';
            app.PlotmapsCheckBox = uicheckbox(app.CompareCellsTab);
            app.PlotmapsCheckBox.Text = 'Plot maps instead of ACs';
            app.LineariseItTab = uitab(app.TabGroup);
            app.LineariseItTab.Title = 'Linearise It';
            app.lin_allCells = uiaxes(app.LineariseItTab);
            xlabel(app.lin_allCells, 'Position (cm)')
            ylabel(app.lin_allCells, {'Cell N'; ''})
            app.lin_allCells.XTick = [];
            app.lin_allCells.YTick = [];
            app.lin_spikebypos = uiaxes(app.LineariseItTab);
            xlabel(app.lin_spikebypos, 'Position (cm)')
            ylabel(app.lin_spikebypos, 'Time (s)')
            app.lin_spikebypos.XTick = [];
            app.lin_spikebypos.YTick = [];
            app.lin_rate = uiaxes(app.LineariseItTab);
            xlabel(app.lin_rate, 'Position (cm)')
            ylabel(app.lin_rate, 'Firing Rate (Hz)')
            app.lin_rate.XTick = [];
            app.lin_rate.YTick = [];
            app.TextArea_2 = uitextarea(app.LineariseItTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Track properties'};
            app.LineariseItButton = uibutton(app.LineariseItTab, 'push');
            app.LineariseItButton.ButtonPushedFcn = createCallbackFcn(app, @LineariseItButtonPushed, true);
            app.LineariseItButton.Text = 'Linearise It';
            app.Lin_cellID = uieditfield(app.LineariseItTab, 'text');
            app.Lin_cellID.Editable = 'off';
            app.TextArea_3 = uitextarea(app.LineariseItTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'All Cells'};
            app.Lin_trialID = uieditfield(app.LineariseItTab, 'text');
            app.Lin_trialID.Editable = 'off';
            app.RundirectionDropDownLabel = uilabel(app.LineariseItTab);
            app.RundirectionDropDownLabel.Text = 'Run direction';
            app.RundirectionDropDown = uidropdown(app.LineariseItTab);
            app.RundirectionDropDown.Items = {'all', 'CW', 'CCW'};
            app.RundirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @RundirectionDropDownValueChanged, true);
            app.RundirectionDropDown.Value = 'all';
            app.TrackLengthcmEditFieldLabel_2 = uilabel(app.LineariseItTab);
            app.TrackLengthcmEditFieldLabel_2.Text = 'Track Length (cm)';
            app.TrackLengthEditField = uieditfield(app.LineariseItTab, 'numeric');
            app.TrackLengthEditField.ValueChangedFcn = createCallbackFcn(app, @TrackLengthEditFieldValueChanged, true);
            app.TrackTypeEditFieldLabel = uilabel(app.LineariseItTab);
            app.TrackTypeEditFieldLabel.Text = 'Track Type';
            app.TrackTypeEditField = uieditfield(app.LineariseItTab, 'text');
            app.TrackTypeEditField.ValueChangedFcn = createCallbackFcn(app, @TrackTypeEditFieldValueChanged, true);
            app.LFPbrowserTab = uitab(app.TabGroup);
            app.LFPbrowserTab.Title = 'LFP browser';
            app.PlotSelectionButton = uibutton(app.mainWin, 'push');
            app.PlotSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGroupsOfMaps, true);
            app.PlotSelectionButton.Text = 'Plot Selection';
            app.LoadDataButton = uibutton(app.mainWin, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DatasetLabel = uilabel(app.mainWin);
            app.DatasetLabel.Text = 'Dataset';
            app.CurrentDatasetEditField = uieditfield(app.mainWin, 'text');
            app.CurrentDatasetEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentDatasetEditFieldValueChanged2, true);
            app.TrialEditFieldLabel = uilabel(app.mainWin);
            app.TrialEditFieldLabel.Text = 'Trial';
            app.TrialEditField = uieditfield(app.mainWin, 'text');
            app.TrialEditField.Editable = 'off';
            app.CellEditFieldLabel = uilabel(app.mainWin);
            app.CellEditFieldLabel.Text = 'Cell #';
            app.CellEditField = uieditfield(app.mainWin, 'text');
            app.CellEditField.Editable = 'off';
            app.SelectDatasetsListBoxLabel = uilabel(app.mainWin);
            app.SelectDatasetsListBoxLabel.Text = {'Select'; 'Datasets'};
            app.SelectDatasetsListBox = uilistbox(app.mainWin);
            app.SelectDatasetsListBox.Items = {};
            app.SelectDatasetsListBox.Multiselect = 'on';
            app.SelectDatasetsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectDatasetsListBoxValueChanged, true);
            app.SelectDatasetsListBox.Value = {};
            app.SelectAllCells = uibutton(app.mainWin, 'push');
            app.SelectAllCells.ButtonPushedFcn = createCallbackFcn(app, @SelectAllCellsPushed, true);
            app.SelectAllCells.Text = {'Select'; 'All'};
            app.SelectAllTrials = uibutton(app.mainWin, 'push');
            app.SelectAllTrials.ButtonPushedFcn = createCallbackFcn(app, @SelectAllTrialsPushed, true);
            app.SelectAllTrials.Text = {'Select'; 'All'};
            app.ExportFiguresButton = uibutton(app.mainWin, 'push');
            app.ExportFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @saveFigs, true);
            app.ExportFiguresButton.Text = 'Export Figures';
            app.BatchMapsButton = uibutton(app.mainWin, 'push');
            app.BatchMapsButton.ButtonPushedFcn = createCallbackFcn(app, @BatchMapsButtonPushed, true);
            app.BatchMapsButton.Text = 'Batch Maps';
            app.SelectAllDatasets = uibutton(app.mainWin, 'push');
            app.SelectAllDatasets.ButtonPushedFcn = createCallbackFcn(app, @SelectAllDatasetsButtonPushed, true);
            app.SelectAllDatasets.Text = {'Select'; 'All'};
            app.AgeEditFieldLabel = uilabel(app.mainWin);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.mainWin, 'text');
            app.AgeEditField.Editable = 'off';
            app.CheckBoxToggleSel = uicheckbox(app.mainWin);
            app.CheckBoxToggleSel.ValueChangedFcn = createCallbackFcn(app, @CheckBoxToggleSelValueChanged, true);
            app.CheckBoxToggleSel.Text = '';
            app.TextArea_4 = uitextarea(app.mainWin);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Toggle remember'; 'selection'};
            % Show the figure after all components are created
            app.mainWin.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainWin)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainWin)
        end
    end
end